classdef drdoproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MainGridLayout         matlab.ui.container.GridLayout
        MainTabGroup           matlab.ui.container.TabGroup
        DetectorTab            matlab.ui.container.Tab
        GridLayout3            matlab.ui.container.GridLayout
        AnalysisPanel          matlab.ui.container.Panel
        GridLayout5            matlab.ui.container.GridLayout
        ClearButton            matlab.ui.control.Button
        SaveSnapshotButton     matlab.ui.control.Button
        ThreatIndicatorLamp    matlab.ui.control.Lamp
        ThreatLevelLabel       matlab.ui.control.Label
        ConfidenceGauge        matlab.ui.control.Gauge
        ConfidenceTitleLabel   matlab.ui.control.Label
        DetectionResultLabel   matlab.ui.control.Label
        DetectionTitleLabel    matlab.ui.control.Label
        AnalyzeSoundButton     matlab.ui.control.Button
        ColormapDropDown       matlab.ui.control.DropDown
        ColormapDropDownLabel  matlab.ui.control.Label
        SpectrogramAxes        matlab.ui.control.UIAxes
        AudioControlPanel      matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        StopAudioButton        matlab.ui.control.Button
        PlayAudioButton        matlab.ui.control.Button
        AudioInfoLabel         matlab.ui.control.Label
        RecordDurationSpinner  matlab.ui.control.Spinner
        RecordAudioButton      matlab.ui.control.Button
        ORLabel                matlab.ui.control.Label
        FileNameLabel          matlab.ui.control.Label
        LoadAudioButton        matlab.ui.control.Button
        WaveformAxes           matlab.ui.control.UIAxes
        EventLogTab            matlab.ui.container.Tab
        GridLayout6            matlab.ui.container.GridLayout
        ExportLogButton        matlab.ui.control.Button
        ClearLogButton         matlab.ui.control.Button
        LogTextArea            matlab.ui.control.TextArea
        TextAreaLabel          matlab.ui.control.Label
        HeaderPanel            matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        TitleLabel             matlab.ui.control.Label
        SystemStatusLamp       matlab.ui.control.Lamp
        LampLabel              matlab.ui.control.Label
        LogoImage              matlab.ui.control.Image
        DateTimeLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        AudioData % Stores audio signal
        Fs % Stores sampling rate
        AudioPlayer % For audioplayer object
        IsPlaying = false;
        CurrentAudioPath = '';
        SimulatedClassNames = {'Gun Shot', 'Siren', 'Engine Idling', 'Dog Bark', 'Ambient Noise'};
        TimerForDateTime % For updating date/time label
        % Placeholder for actual model and feature params
        TrainedModel_Placeholder = true; % True if model is "loaded"
        % FeatureExtractionParams_Placeholder = struct(...);
    end
    
    methods (Access = private)
        
        function plotWaveform(app)
             if isempty(app.AudioData), return; end
             timeVector = (0:length(app.AudioData)-1) / app.Fs;
             plot(app.WaveformAxes, timeVector, app.AudioData);
             xlabel(app.WaveformAxes, 'Time (s)');
             ylabel(app.WaveformAxes, 'Amplitude');
             title(app.WaveformAxes, 'Audio Waveform');
             grid(app.WaveformAxes, 'on');
             axis(app.WaveformAxes, 'tight');
         end

         function plotSpectrogram(app)
            if isempty(app.AudioData) || isempty(app.Fs) % Keep the robust check
                cla(app.SpectrogramAxes);
                title(app.SpectrogramAxes, 'Audio Spectrogram (No Data)');
                xlabel(app.SpectrogramAxes, 'Time (s)');
                ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
                colorbar(app.SpectrogramAxes, 'off');
                return;
            end

            try
                windowLengthSec = 0.025; % 25 ms
                overlapLengthSec = 0.015; % 15 ms
                
                win = hamming(round(windowLengthSec * app.Fs), 'periodic');
                overlap = round(overlapLengthSec * app.Fs); 
                
                % Ensure window length is at least 2 samples (important fix)
                if length(win) < 2
                    app.logMessage('Warning: Adjusted spectrogram window size to 2 samples due to short duration or low Fs.');
                    win = hamming(2); % Fallback to smallest valid window
                end

                nfft = max(256, 2^nextpow2(length(win))); 
                
                numBands = 64; 
                % Ensure numBands is appropriate (important fix)
                if numBands > nfft/2 + 1
                    numBands = floor(nfft/2); 
                    app.logMessage(['Warning: Adjusted numBands to ', num2str(numBands), ' for spectrogram.']);
                end
                
                minFreq = 20;
                maxFreq = min(16000, app.Fs/2); % Removed -1, as Fs/2 is the Nyquist limit
                
                % Ensure FrequencyRange is valid (important fix)
                if minFreq >= maxFreq || app.Fs/2 < 20
                    app.logMessage('Warning: Sampling rate too low for desired frequency range. Using full Nyquist.');
                    minFreq = 0;
                    maxFreq = app.Fs/2;
                end

                % Ensure audioData is a column vector
                audioDataForMel = app.AudioData(:); 
                
                % Check if audio data is long enough for even one window (important fix)
                if length(audioDataForMel) < length(win)
                    app.logMessage('Warning: Audio data too short for even one spectrogram window. Cannot plot spectrogram.');
                    cla(app.SpectrogramAxes);
                    title(app.SpectrogramAxes, 'Audio Spectrogram (Too Short)');
                    xlabel(app.SpectrogramAxes, 'Time (s)');
                    ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
                    colorbar(app.SpectrogramAxes, 'off');
                    return;
                end

                % THIS IS THE LINE TO CAREFULLY RE-TYPE/CHECK:
                [S, f_spec, t_spec] = melSpectrogram(audioDataForMel, app.Fs, ...
                                         'Window', win, ...
                                         'OverlapLength', overlap, ...
                                         'FFTLength', nfft, ...
                                         'NumBands', numBands, ...
                                         'FrequencyRange', [minFreq, maxFreq]);
                
                % Remaining plotting code (assuming no issues here)
                imagesc(app.SpectrogramAxes, t_spec, f_spec, 10*log10(S+eps)); % dB scale
                axis(app.SpectrogramAxes, 'xy');
                
                if isvalid(app.ColormapDropDown)
                    colormap(app.SpectrogramAxes, app.ColormapDropDown.Value);
                else
                    colormap(app.SpectrogramAxes, 'parula'); % Default if dropdown is invalid
                    app.logMessage('Warning: Colormap dropdown invalid, defaulting to Parula.');
                end
                
                colorbar(app.SpectrogramAxes, 'on');
                xlabel(app.SpectrogramAxes, 'Time (s)');
                ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
                title(app.SpectrogramAxes, 'Mel Spectrogram');

            catch ME
                app.logMessage(['Spectrogram error: ', ME.message]);
                cla(app.SpectrogramAxes); 
                title(app.SpectrogramAxes, 'Spectrogram Error');
                xlabel(app.SpectrogramAxes, 'Time (s)');
                ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
                colorbar(app.SpectrogramAxes, 'off');
                uialert(app.UIFigure, ['Could not plot spectrogram: ' ME.message], 'Spectrogram Error', 'Icon', 'error');
            end
            
         end
        
        function resetDetectionResults(app)
            app.DetectionResultLabel.Text = '---';
            app.DetectionResultLabel.FontColor = [0 0 0]; % Reset to default
            app.ConfidenceGauge.Value = 0;
            app.ThreatIndicatorLamp.Color = [0.5 0.5 0.5]; % Grey
        end

        function audioPlaybackFinished(app)
            app.IsPlaying = false;
            if ~isempty(app.AudioData) % Ensure audio is still loaded
                 app.PlayAudioButton.Enable = 'on';
            end
            app.StopAudioButton.Enable = 'off';
            if isvalid(app.UIFigure) % Check if app is still running
                app.logMessage('Audio playback finished.');
            end
        end

        function ColormapDropDownValueChanged(app, ~)
            if ~isempty(app.AudioData) && ishghandle(app.SpectrogramAxes.Children) % Check if spectrogram is plotted
                colormap(app.SpectrogramAxes, app.ColormapDropDown.Value);
                app.logMessage(['Spectrogram colormap changed to: ', app.ColormapDropDown.Value]);
            end
        end

        function ClearLogButtonPushed(app, ~)
            app.LogTextArea.Value = {sprintf('%s Log Cleared.', datetime("now"))};
        end

        function ExportLogButtonPushed(app, ~)
            [file, path] = uiputfile('AuraShield_Log.txt', 'Save Log As');
            if file ~= 0
                try
                    fid = fopen(fullfile(path, file), 'w');
                    for i = 1:length(app.LogTextArea.Value)
                        fprintf(fid, '%s\n', app.LogTextArea.Value{i});
                    end
                    fclose(fid);
                    app.logMessage(['Log exported to: ', fullfile(path, file)]);
                catch ME
                    app.logMessage(['Error exporting log: ', ME.message]);
                    uialert(app.UIFigure, 'Could not export log.', 'Export Error');
                end
            end
        end

        function ApplySettingsButtonPushed(app, ~)
            sensitivity = app.SensitivityKnob.Value; % Or slider
            dogBarkEnabled = app.DogBarkCheckBox.Value;
            stealthMode = app.StealthModeCheckBox.Value;
            selectedTheme = app.ThemeDropDown.Value;
            app.logMessage(sprintf('Simulated settings applied: Sensitivity=%s, DogBark=%d, Stealth=%d, Theme=%s', ...
                sensitivity, dogBarkEnabled, stealthMode, selectedTheme));
            uialert(app.UIFigure, 'Settings applied (simulation only). UI theme change requires restart or advanced coding.', 'Settings Applied');
            % Actual theme change would involve iterating through components and setting colors
            % if strcmp(selectedTheme, "Dark Pro")
            %   app.UIFigure.Color = [0.2 0.2 0.2];
            %   % ... and for all other components and text
            % elseif strcmp(selectedTheme, "Light Breeze")
            %   app.UIFigure.Color = [0.94 0.94 0.94]; % Default MATLAB light
            %   % ...
            % end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SystemStatusLamp.Color = [0 1 0]; % Green - Ready
            app.TitleLabel.Text = "AuraShield - Sound Intelligence";
            app.TitleLabel.FontWeight = 'bold';
            app.TitleLabel.FontSize = 18;
            app.RecordDurationSpinner.Value = 5; % Default 5s
            app.AnalyzeSoundButton.Enable = 'off';
            app.PlayAudioButton.Enable = 'off';
            app.StopAudioButton.Enable = 'off';
            app.ConfidenceGauge.Value = 0;
            app.ThreatIndicatorLamp.Color = [0.5 0.5 0.5]; % Grey
            app.DetectionResultLabel.Text = '---';
            app.DetectionResultLabel.FontColor = [0 0 0]; % Black for light theme default
            app.ColormapDropDown.Items = {'Parula', 'Jet', 'Hot', 'Cool', 'Gray', 'Viridis'};
            app.ColormapDropDown.Value = 'Parula';

            % Start DateTime Timer (Optional)
            app.TimerForDateTime = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ... % Update every second
                'TimerFcn', @(~,~) app.updateDateTimeLabel());
            start(app.TimerForDateTime);
            % Initial plot styling
            title(app.WaveformAxes, 'Load or Record Audio');
            xlabel(app.WaveformAxes, 'Time (s)'); ylabel(app.WaveformAxes, 'Amplitude');
            grid(app.WaveformAxes, 'on');
            title(app.SpectrogramAxes, 'Audio Spectrogram');
            xlabel(app.SpectrogramAxes, 'Time (s)'); ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
            colorbar(app.SpectrogramAxes, 'off'); % Initially off
            app.logMessage('GUI startup complete. Waiting for audio input.');
        end
        function updateDateTimeLabel(app)
            if isvalid(app.UIFigure) % Check if app is still running
                 app.DateTimeLabel.Text = string(datetime("now"), 'dd-MMM-yyyy HH:mm:ss');
            end
        end
        function logMessage(app, message)
            currentDatetime = datetime("now");
            timestampStr = string(currentDatetime, 'HH:mm:ss');
            newLogEntry = sprintf('[%s] %s', timestampStr, message);
            fprintf('%s\n', newLogEntry);
            currentLog = app.LogTextArea.Value;
            if isempty(currentLog) || (isstring(currentLog) && currentLog == "") || (ischar(currentLog) && all(currentLog == ' '))
                app.LogTextArea.Value = newLogEntry;
            else
                if ischar(currentLog)
                    currentLog = {currentLog};
                end
                app.LogTextArea.Value = [currentLog; newLogEntry];
            end
            if numel(app.LogTextArea.Value) > 200
                app.LogTextArea.Value = app.LogTextArea.Value(end-199:end);
            end
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.wav;*.mp3;*.m4a', 'Audio Files (*.wav, *.mp3, *.m4a)'}, 'Select Audio File');
            if fileName ~= 0
                app.CurrentAudioPath = fullfile(filePath, fileName);
                app.FileNameLabel.Text = fileName;
                app.logMessage(['Loading audio: ', app.CurrentAudioPath]);
                app.SystemStatusLamp.Color = [1 0.8 0]; % Yellow - Processing
                drawnow;
                try
                    [app.AudioData, app.Fs] = audioread(app.CurrentAudioPath);
                    if size(app.AudioData, 2) > 1, app.AudioData = mean(app.AudioData, 2); end % Mono
                    duration = length(app.AudioData) / app.Fs;
                    app.AudioInfoLabel.Text = sprintf('Duration: %.2fs\nFs: %d Hz\nChannels: %d', ...
                                                      duration, app.Fs, size(app.AudioData,2));
                    plotWaveform(app);
                    plotSpectrogram(app); % Plot initial spectrogram
                    app.AnalyzeSoundButton.Enable = 'on';
                    app.PlayAudioButton.Enable = 'on';
                    app.SystemStatusLamp.Color = [0 1 0]; % Green
                    app.logMessage('Audio loaded successfully.');
                    resetDetectionResults(app);
                catch ME
                    app.SystemStatusLamp.Color = [1 0 0]; % Red - Error
                    app.logMessage(['Error loading audio: ', ME.message]);
                    uialert(app.UIFigure, ['Could not load audio: ' ME.message], 'Load Error');
                    app.FileNameLabel.Text = 'Load Error. Try Again.';
                end
            end
        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            duration = app.RecordDurationSpinner.Value;
            app.FileNameLabel.Text = sprintf('Recording for %ds...', duration);
            app.logMessage(sprintf('Starting %.1fs recording...', duration));
            app.SystemStatusLamp.Color = [1 0.5 0]; % Orange - Recording
            % Disable buttons during recording
            app.LoadAudioButton.Enable = 'off'; 
            app.RecordAudioButton.Enable = 'off';
            app.AnalyzeSoundButton.Enable = 'off';
            drawnow;
            try
                recorder = audiorecorder(44100, 16, 1); % Standard Fs, bit depth, channels
                recordblocking(recorder, duration); % Synchronous recording
                app.AudioData = getaudiodata(recorder);
                app.Fs = recorder.SampleRate;
                app.CurrentAudioPath = ''; % No file path for recorded audio
                app.FileNameLabel.Text = sprintf('Recorded Audio (%.1fs)', duration);
                duration_rec = length(app.AudioData) / app.Fs;
                app.AudioInfoLabel.Text = sprintf('Duration: %.2fs\nFs: %d Hz\nChannels: %d', ...
                                                  duration_rec, app.Fs, size(app.AudioData,2));
                plotWaveform(app);
                plotSpectrogram(app);
                app.AnalyzeSoundButton.Enable = 'on';
                app.PlayAudioButton.Enable = 'on';
                app.SystemStatusLamp.Color = [0 1 0]; % Green
                app.logMessage('Recording complete.');
                resetDetectionResults(app);
            catch ME
                app.SystemStatusLamp.Color = [1 0 0]; % Red
                app.logMessage(['Error during recording: ', ME.message]);
                uialert(app.UIFigure, ['Recording failed: ' ME.message], 'Recording Error');
                app.FileNameLabel.Text = 'Recording Failed.';
            end
            % Re-enable buttons
            app.LoadAudioButton.Enable = 'on'; 
            app.RecordAudioButton.Enable = 'on';
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            if ~isempty(app.AudioData) && ~app.IsPlaying
                app.AudioPlayer = audioplayer(app.AudioData, app.Fs);
                app.AudioPlayer.StopFcn = @(~,~) audioPlaybackFinished(app);
                play(app.AudioPlayer);
                app.IsPlaying = true;
                app.PlayAudioButton.Enable = 'off';
                app.StopAudioButton.Enable = 'on';
                app.logMessage('Audio playback started.');
            end
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            if app.IsPlaying && ~isempty(app.AudioPlayer)
                stop(app.AudioPlayer);
                % StopFcn will handle resetting IsPlaying and button states
            end
        end

        % Button pushed function: AnalyzeSoundButton
        function AnalyzeSoundButtonPushed(app, event)
            if isempty(app.AudioData)
                uialert(app.UIFigure, 'No audio data to analyze.', 'Analysis Error');
                return;
            end
            if ~app.TrainedModel_Placeholder % In future, check if model is actually loaded
                 uialert(app.UIFigure, 'AI Model not loaded. Cannot analyze.', 'Model Error');
                return;
            end
            app.logMessage('Simulating sound analysis...');
            app.SystemStatusLamp.Color = [1 0.8 0]; % Yellow - Processing
            app.AnalyzeSoundButton.Text = 'ANALYZING...';
            app.AnalyzeSoundButton.Enable = 'off';
            drawnow;
            % --- SIMULATED ANALYSIS ---
            pause(rand()*1.5 + 0.5); % Simulate processing time (0.5 to 2 seconds)
            randomIndex = randi(length(app.SimulatedClassNames));
            simulatedLabel = app.SimulatedClassNames{randomIndex};
            simulatedConfidence = rand()*0.4 + 0.6; % Confidence between 60% and 100%
            % --- USER: Replace above with actual feature extraction & model prediction ---
            % Example:
            % features = extractMyFeatures(app.AudioData, app.Fs, app.FeatureExtractionParams_Placeholder);
            % [predictedLabel, confidenceScore] = predict(app.TrainedModel_Placeholder, features);
            % predictedLabelStr = char(predictedLabel);
            % --------------------------------------------------------------------------
            app.DetectionResultLabel.Text = simulatedLabel;
            app.ConfidenceGauge.Value = simulatedConfidence * 100;
            % Update lamp and label colors based on simulated threat
            switch lower(simulatedLabel)
                case {'gun shot', 'siren'}
                    app.ThreatIndicatorLamp.Color = [1, 0, 0]; % Red
                    app.DetectionResultLabel.FontColor = [1, 0, 0];
                case 'engine idling'
                    app.ThreatIndicatorLamp.Color = [1, 1, 0]; % Yellow
                    app.DetectionResultLabel.FontColor = [0.6, 0.6, 0]; % Darker yellow for text
                case 'dog bark'
                     app.ThreatIndicatorLamp.Color = [0, 0.7, 0.7]; % Teal
                    app.DetectionResultLabel.FontColor = [0, 0.5, 0.5];
                otherwise % Ambient Noise
                    app.ThreatIndicatorLamp.Color = [0, 1, 0]; % Green
                    app.DetectionResultLabel.FontColor = [0, 0.5, 0]; % Darker green
            end
            app.logMessage(sprintf('Simulated Detection: %s (Confidence: %.1f%%)', simulatedLabel, simulatedConfidence*100));
            app.SystemStatusLamp.Color = [0 1 0]; % Green
            app.AnalyzeSoundButton.Text = 'ANALYZE SOUND';
            app.AnalyzeSoundButton.Enable = 'on';
        end

        % Button pushed function: SaveSnapshotButton
        function SaveSnapshotButtonPushed(app, event)
            if isempty(app.AudioData)
                 app.logMessage('Nothing to save. Load or record audio first.');
                 return;
            end

            app.logMessage('Initiating snapshot save...');
            
            % Open a dialog to choose save location and file name
            [fileName, filePath, ~] = uiputfile(...
                {'*.png', 'PNG Image (*.png)'; ...
                 '*.jpg', 'JPEG Image (*.jpg)'; ...
                 '*.pdf', 'PDF Document (*.pdf)'}, ...
                'Save Spectrogram Snapshot', ...
                'AuraShield_Spectrogram.png'); % Default file name

            if fileName ~= 0 % If user didn't cancel
                fullFilePath = fullfile(filePath, fileName);
                
                try
                    % Save the SpectrogramAxes
                    exportgraphics(app.SpectrogramAxes, fullFilePath, 'Resolution', 300); % 300 DPI for good quality

                    app.logMessage(['Spectrogram snapshot saved to: ', fullFilePath]);

                    % Optionally, you could also save the waveform
                    % prompt = 'Do you also want to save the Waveform?';
                    % title = 'Save Waveform?';
                    % selection = uiconfirm(app.UIFigure, prompt, title, 'Options', {'Yes','No'}, 'DefaultOption', 1);
                    % if strcmp(selection,'Yes')
                    %     [wfFileName, wfFilePath] = uiputfile('AuraShield_Waveform.png', 'Save Waveform Snapshot');
                    %     if wfFileName ~= 0
                    %         exportgraphics(app.WaveformAxes, fullfile(wfFilePath, wfFileName), 'Resolution', 300);
                    %         app.logMessage(['Waveform snapshot saved to: ', fullfile(wfFilePath, wfFileName)]);
                    %     end
                    % end

                catch ME
                    app.logMessage(['Error saving snapshot: ', ME.message]);
                    uialert(app.UIFigure, ['Could not save snapshot: ' ME.message], 'Save Error', 'Icon', 'error');
                end
            else
                app.logMessage('Snapshot save cancelled.');
            end


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.WaveformAxes); title(app.WaveformAxes, 'Load or Record Audio');
            cla(app.SpectrogramAxes); title(app.SpectrogramAxes, 'Audio Spectrogram'); colorbar(app.SpectrogramAxes, 'off');
            app.AudioData = []; app.Fs = [];
            app.FileNameLabel.Text = 'No file loaded';
            app.AudioInfoLabel.Text = 'Duration: --s, Fs: --Hz, Channels: --';
            resetDetectionResults(app);
            app.AnalyzeSoundButton.Enable = 'off';
            app.PlayAudioButton.Enable = 'off';
            app.StopAudioButton.Enable = 'off';
            if app.IsPlaying && ~isempty(app.AudioPlayer), stop(app.AudioPlayer); end
            app.logMessage('Analysis cleared.');
        end

        % Button down function: WaveformAxes
        function WaveformAxesButtonDown(app, event)
            if isempty(app.AudioData), return; end
            timeVector = (0:length(app.AudioData)-1) / app.Fs;
            plot(app.WaveformAxes, timeVector, app.AudioData);
            xlabel(app.WaveformAxes, 'Time (s)');
            ylabel(app.WaveformAxes, 'Amplitude');
            title(app.WaveformAxes, 'Audio Waveform');
            grid(app.WaveformAxes, 'on');
            axis(app.WaveformAxes, 'tight');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.HeaderPanel = uipanel(app.MainGridLayout);
            app.GridLayout2 = uigridlayout(app.HeaderPanel);
            app.DateTimeLabel = uilabel(app.GridLayout2);
            app.DateTimeLabel.Text = 'dd-mmm-yyyy HH:MM:SS';
            app.LogoImage = uiimage(app.GridLayout2);
            app.LampLabel = uilabel(app.GridLayout2);
            app.LampLabel.Text = 'Lamp';
            app.SystemStatusLamp = uilamp(app.GridLayout2);
            app.TitleLabel = uilabel(app.GridLayout2);
            app.TitleLabel.Text = 'AuraShield- Sound Prediction System';
            app.MainTabGroup = uitabgroup(app.MainGridLayout);
            app.DetectorTab = uitab(app.MainTabGroup);
            app.DetectorTab.Title = 'Detector';
            app.GridLayout3 = uigridlayout(app.DetectorTab);
            app.AudioControlPanel = uipanel(app.GridLayout3);
            app.AudioControlPanel.Title = 'Audio Control';
            app.GridLayout4 = uigridlayout(app.AudioControlPanel);
            app.WaveformAxes = uiaxes(app.GridLayout4);
            title(app.WaveformAxes, 'Title')
            xlabel(app.WaveformAxes, 'X')
            ylabel(app.WaveformAxes, 'Y')
            zlabel(app.WaveformAxes, 'Z')
            app.WaveformAxes.ButtonDownFcn = createCallbackFcn(app, @WaveformAxesButtonDown, true);
            app.LoadAudioButton = uibutton(app.GridLayout4, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio File';
            app.FileNameLabel = uilabel(app.GridLayout4);
            app.FileNameLabel.Text = 'No file loaded';
            app.ORLabel = uilabel(app.GridLayout4);
            app.ORLabel.Text = 'OR';
            app.RecordAudioButton = uibutton(app.GridLayout4, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.RecordDurationSpinner = uispinner(app.GridLayout4);
            app.RecordDurationSpinner.Limits = [1 60];
            app.RecordDurationSpinner.Placeholder = 'Duration (s)';
            app.RecordDurationSpinner.Value = 5;
            app.AudioInfoLabel = uilabel(app.GridLayout4);
            app.AudioInfoLabel.Text = 'Duration: --s, Fs: --Hz, Channels: --';
            app.PlayAudioButton = uibutton(app.GridLayout4, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play';
            app.StopAudioButton = uibutton(app.GridLayout4, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop';
            app.AnalysisPanel = uipanel(app.GridLayout3);
            app.AnalysisPanel.Title = 'Sound Analysis';
            app.GridLayout5 = uigridlayout(app.AnalysisPanel);
            app.SpectrogramAxes = uiaxes(app.GridLayout5);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'X')
            ylabel(app.SpectrogramAxes, 'Y')
            zlabel(app.SpectrogramAxes, 'Z')
            app.ColormapDropDownLabel = uilabel(app.GridLayout5);
            app.ColormapDropDownLabel.Text = 'Colormap:';
            app.ColormapDropDown = uidropdown(app.GridLayout5);
            app.AnalyzeSoundButton = uibutton(app.GridLayout5, 'push');
            app.AnalyzeSoundButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSoundButtonPushed, true);
            app.AnalyzeSoundButton.Text = 'ANALYZE SOUND';
            app.DetectionTitleLabel = uilabel(app.GridLayout5);
            app.DetectionTitleLabel.Text = 'Threat Detection:';
            app.DetectionResultLabel = uilabel(app.GridLayout5);
            app.DetectionResultLabel.Text = '---------';
            app.ConfidenceTitleLabel = uilabel(app.GridLayout5);
            app.ConfidenceTitleLabel.Text = 'Confidence Level:';
            app.ConfidenceGauge = uigauge(app.GridLayout5, 'circular');
            app.ThreatLevelLabel = uilabel(app.GridLayout5);
            app.ThreatLevelLabel.Text = 'Threat Level:';
            app.ThreatIndicatorLamp = uilamp(app.GridLayout5);
            app.SaveSnapshotButton = uibutton(app.GridLayout5, 'push');
            app.SaveSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSnapshotButtonPushed, true);
            app.SaveSnapshotButton.Text = 'Save Snapshot';
            app.ClearButton = uibutton(app.GridLayout5, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear All';
            app.EventLogTab = uitab(app.MainTabGroup);
            app.EventLogTab.Title = 'Event Log';
            app.GridLayout6 = uigridlayout(app.EventLogTab);
            app.TextAreaLabel = uilabel(app.GridLayout6);
            app.TextAreaLabel.Text = 'Text Area';
            app.LogTextArea = uitextarea(app.GridLayout6);
            app.ClearLogButton = uibutton(app.GridLayout6, 'push');
            app.ClearLogButton.Text = 'Clear Log';
            app.ExportLogButton = uibutton(app.GridLayout6, 'push');
            app.ExportLogButton.Text = 'Export Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drdoproject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end