classdef drdoproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MainGridLayout               matlab.ui.container.GridLayout
        MainTabGroup                 matlab.ui.container.TabGroup
        DetectorTab                  matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        AnalysisPanel                matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        ClearButton                  matlab.ui.control.Button
        SaveSnapshotButton           matlab.ui.control.Button
        ThreatIndicatorLamp          matlab.ui.control.Lamp
        ThreatLevelLabel             matlab.ui.control.Label
        ConfidenceGauge              matlab.ui.control.Gauge
        ConfidenceTitleLabel         matlab.ui.control.Label
        DetectionResultLabel         matlab.ui.control.Label
        DetectionTitleLabel          matlab.ui.control.Label
        AnalyzeSoundButton           matlab.ui.control.Button
        ColormapDropDown             matlab.ui.control.DropDown
        ColormapDropDownLabel        matlab.ui.control.Label
        SpectrogramAxes              matlab.ui.control.UIAxes
        AudioControlPanel            matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        StopAudioButton              matlab.ui.control.Button
        PlayAudioButton              matlab.ui.control.Button
        AudioInfoLabel               matlab.ui.control.Label
        RecordDurationSpinner        matlab.ui.control.Spinner
        RecordAudioButton            matlab.ui.control.Button
        ORLabel                      matlab.ui.control.Label
        FileNameLabel                matlab.ui.control.Label
        LoadAudioButton              matlab.ui.control.Button
        WaveformAxes                 matlab.ui.control.UIAxes
        EvaluationTab                matlab.ui.container.Tab
        Panel_15                     matlab.ui.container.Panel
        LSTMAccuracyLabel            matlab.ui.control.Label
        LSTMGauge                    matlab.ui.control.Gauge
        LSTMGaugeLabel               matlab.ui.control.Label
        LSTMLabel                    matlab.ui.control.Label
        Panel_14                     matlab.ui.container.Panel
        KNNAccuracyLabel             matlab.ui.control.Label
        KNNAccuracyGauge             matlab.ui.control.Gauge
        KNNLabel                     matlab.ui.control.Label
        Panel_13                     matlab.ui.container.Panel
        CNNAccuracyLabel             matlab.ui.control.Label
        CNNAccuracyGauge             matlab.ui.control.Gauge
        CNNLabel                     matlab.ui.control.Label
        Panel_12                     matlab.ui.container.Panel
        RandomForestAccuracyLabel    matlab.ui.control.Label
        RandomForestAccuracyGauge    matlab.ui.control.Gauge
        RandomForestLabel            matlab.ui.control.Label
        Panel_17                     matlab.ui.container.Panel
        SVMAccuracyGauge             matlab.ui.control.Gauge
        SVMAccuracyLabel             matlab.ui.control.Label
        SVMLabel                     matlab.ui.control.Label
        EventLogTab                  matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        ExportLogButton              matlab.ui.control.Button
        ClearLogButton               matlab.ui.control.Button
        LogTextArea                  matlab.ui.control.TextArea
        EventLogsLabel               matlab.ui.control.Label
        SettingsTab                  matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        ModelSelectionPanel          matlab.ui.container.Panel
        ButtonGroup                  matlab.ui.container.ButtonGroup
        SelectModelfromtheDropDownLabel  matlab.ui.control.Label
        ModelDropDown_2              matlab.ui.control.DropDown
        Label                        matlab.ui.control.Label
        ResettoDefaultsButton        matlab.ui.control.Button
        SaveSettingsButton           matlab.ui.control.Button
        AboutTab                     matlab.ui.container.Tab
        GridLayout8                  matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        Home                         matlab.ui.container.Tab
        GridLayout13                 matlab.ui.container.GridLayout
        ClicktoLearnMoreAboutTheProjectButton  matlab.ui.control.Button
        GridLayout14                 matlab.ui.container.GridLayout
        Panel_21                     matlab.ui.container.Panel
        GridLayout18                 matlab.ui.container.GridLayout
        ConfigurableParametersLabel  matlab.ui.control.Label
        UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel  matlab.ui.control.Label
        Panel_20                     matlab.ui.container.Panel
        GridLayout17                 matlab.ui.container.GridLayout
        AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel  matlab.ui.control.Label
        RealtimeProcessingLabel      matlab.ui.control.Label
        Panel_19                     matlab.ui.container.Panel
        GridLayout16                 matlab.ui.container.GridLayout
        ThreatDetectionLabel         matlab.ui.control.Label
        IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel  matlab.ui.control.Label
        Panel_18                     matlab.ui.container.Panel
        GridLayout15                 matlab.ui.container.GridLayout
        AnalyticsReportingLabel      matlab.ui.control.Label
        ComprehensivedataanalysisandvisualizationforsecurityassessmentLabel  matlab.ui.control.Label
        CoreCapabilitiesLabel_3      matlab.ui.control.Label
        AuraShieldLabel              matlab.ui.control.Label
        SoundIntrusionSystemLabel    matlab.ui.control.Label
        AdvancedacousticmonitoringsolutiondevelopedforDRDOusingcuttingedgeMATLABalgorithmsLabel  matlab.ui.control.Label
        AboutProject                 matlab.ui.container.Tab
        GridLayout12                 matlab.ui.container.GridLayout
        DEVELOPMENTTEAMPanel         matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        PriyanshuSrivastavaLabel     matlab.ui.control.Label
        AmitanshSrivastavaLabel      matlab.ui.control.Label
        AdityaRajSrivastavaLabel     matlab.ui.control.Label
        TejasPalLabel                matlab.ui.control.Label
        AyushiMishraLabel            matlab.ui.control.Label
        AnshitaKapkotiLabel          matlab.ui.control.Label
        PROJECTHIGHLIGHTSPanel       matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        ScalablearchitecturesupportingmultiplesensornetworksLabel  matlab.ui.control.Label
        IntegrationcapabilitieswithexistingsecurityinfrastructureLabel  matlab.ui.control.Label
        MachinelearningalgorithmsforimproveddetectionaccuracyLabel  matlab.ui.control.Label
        ComprehensiveloggingandaudittrailfunctionalityLabel  matlab.ui.control.Label
        RealtimevisualizationofacousticdataandthreatlevelsLabel  matlab.ui.control.Label
        MATLABbasedimplementationforrobustsignalprocessingLabel  matlab.ui.control.Label
        HeaderPanel                  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        DateTimeLabel                matlab.ui.control.Label
        TitleLabel                   matlab.ui.control.Label
        SystemStatusLamp             matlab.ui.control.Lamp
        LampLabel                    matlab.ui.control.Label
        LogoImage                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        AudioData % Stores audio signal
        Fs % Stores sampling rate
        AudioPlayer % For audioplayer object
        IsPlaying = false;
        CurrentAudioPath = '';
        SimulatedClassNames = {'Gun Shot', 'Siren', 'Engine Idling', 'Dog Bark', 'Ambient Noise'};
        TimerForDateTime % For updating date/time label
        % Placeholder for actual model and feature params
        TrainedModel_Placeholder = true; % True if model is "loaded"
        % FeatureExtractionParams_Placeholder = struct(...);
        PrefGroup = 'AuraShieldSettings'
        DefaultSettings
        ActiveModel % Holds the currently loaded machine learning model
        ActiveModelName = ''; % Name of the loaded model, e.g., 'SVM'
        MFCC
        SVMModel

        ModelDropDown

    end


    properties (Access = public)
        AdditionalSettingsPanel      matlab.ui.container.Panel
        SelectedModel
    end
    
    methods (Access = private)

        % --- ADD THIS ENTIRE NEW FUNCTION ---
        function loadActiveModel(app)
    settings = readSettingsFromUI(app); % Get current UI settings

    modelToLoad = '';
    

    % Prevent reloading the same model if it's already active
    if strcmp(app.ActiveModelName, modelToLoad)
        app.logMessage(['Model ''', modelToLoad, ''' is already loaded.']);
        return;
    end
    app.logMessage(['Attempting to load model: ', modelToLoad]);

    % Assumes the 'models' folder is in the project root relative to where MATLAB is running
    % Corrected path: It's 'models', not 'data' if your folder is named 'models'
    modelPath = fullfile('models');

    try
        switch modelToLoad
            case 'SVM'
                loadedData = load(fullfile(modelPath, 'svm_model.mat'));
                % Check for common variable names inside the .mat file
                if isfield(loadedData, 'svmModel')
                    app.ActiveModel = loadedData.svmModel;
                elseif isfield(loadedData, 'trainedModel')
                    app.ActiveModel = loadedData.trainedModel;
                else
                    error('Could not find a valid model variable in svm_model.mat.');
                end
            case 'CNN'
                loadedData = load(fullfile(modelPath, 'cnn_UrbanSound_MFCC.mat'));
                if isfield(loadedData, 'net') % <-- THIS IS THE EXPECTED VARIABLE NAME
                    app.ActiveModel = loadedData.net; % <-- Load the 'net' variable
                else
                    % If 'net' is not found, throw a clear error message.
                    % This means your CNN training script did not save the 'net' variable
                    % into 'cnn_UrbanSound_MFCC.mat' correctly.
                    error('Expected variable "net" not found in cnn_UrbanSound_MFCC.mat. Please ensure the CNN training script saved the model as "net".');
                end
            case 'KNN'
                loadedData = load(fullfile(modelPath, 'knn_model.mat'));
                % Check for common variable names inside the .mat file
                if isfield(loadedData, 'knnModel')
                    app.ActiveModel = loadedData.knnModel; % <-- FIXED: Should be loadedData.knnModel
                elseif isfield(loadedData, 'trainedModel') % Less common for KNN, but good fallback
                    app.ActiveModel = loadedData.trainedModel;
                else
                    error('Could not find a valid model variable in knn_model.mat.');
                end
            case 'RandomForest'
                loadedData = load(fullfile(modelPath, 'rfModel_MFCC.mat'));
                if isfield(loadedData, 'rfModel')
                    app.ActiveModel = loadedData.rfModel;
                else
                    error('Could not find a valid model variable in rfModel_MFCC.mat.');
                end
            case 'LSTM'
                loadedData = load(fullfile(modelPath, 'lstm_model.mat'));

                % Check for common variable names inside the .mat file
                if isfield(loadedData, 'lstmModel')
                    app.ActiveModel = loadedData.lstmModel;  % Use the model as-is
                elseif isfield(loadedData, 'net')
                    app.ActiveModel = loadedData.net;        % In case it's stored as 'net'
                else
                    error('Could not find a valid model variable in lstm_model.mat.');
                end

            % Add cases for 'CNN-1D', 'CNN-2D', 'RNN/LSTM' here as you implement them

            otherwise
                error(['Model ''', modelToLoad, ''' is not yet supported for loading.']);
        end

        app.ActiveModelName = modelToLoad;
        app.logMessage(['Successfully loaded model: ', app.ActiveModelName]);
        uialert(app.UIFigure, ['Model ''', app.ActiveModelName, ''' is now active.'], 'Model Loaded');

    catch ME
        app.ActiveModel = [];
        app.ActiveModelName = 'None';
        app.logMessage(['Error loading model: ', ME.message]);
        uialert(app.UIFigure, ['Failed to load model ''', modelToLoad, '''. Reason: ', ME.message], 'Model Load Error', 'Icon', 'error');
    end
end
        function plotWaveform(app)
             if isempty(app.AudioData), return; end
             timeVector = (0:length(app.AudioData)-1) / app.Fs;
             plot(app.WaveformAxes, timeVector, app.AudioData);
             xlabel(app.WaveformAxes, 'Time (s)');
             ylabel(app.WaveformAxes, 'Amplitude');
             title(app.WaveformAxes, 'Audio Waveform');
             grid(app.WaveformAxes, 'on');
             axis(app.WaveformAxes, 'tight');
             DefaultSettings = struct('ModelSelection', 'Autoselect best model', 'ThresholdSelection', 'Autoselect (>80%)');
             PrefGroup = 'AuraShieldSettings'; % Group name for saving preferences
        end


        function plotSpectrogram(app)
            if isempty(app.AudioData) || isempty(app.Fs)
                cla(app.SpectrogramAxes);
                title(app.SpectrogramAxes, 'No Audio Data');
                xlabel(app.SpectrogramAxes, 'Time (s)');
                ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
                return;
            end

            try
                % Audio signal
                x = app.AudioData(:); % Ensure column
                fs = app.Fs;

                % Parameters
                winLength = round(0.03 * fs); % 30 ms window
                overlap = round(0.02 * fs);   % 20 ms overlap
                nfft = 1024;

                % Generate spectrogram
                [S, F, T] = spectrogram(x, winLength, overlap, nfft, fs);

                % Convert to decibels
                S_dB = 10 * log10(abs(S) + eps);

                % Plot on UIAxes
                imagesc(app.SpectrogramAxes, T, F, S_dB);
                axis(app.SpectrogramAxes, 'xy');
                xlabel(app.SpectrogramAxes, 'Time (s)');
                ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
                title(app.SpectrogramAxes, 'Spectrogram');
                colorbar(app.SpectrogramAxes);

                % Optional colormap
                try
                    colormap(app.SpectrogramAxes, app.ColormapDropDown.Value);
                catch
                    colormap(app.SpectrogramAxes, 'parula');
                end

            catch ME
                app.logMessage(['Spectrogram plotting error: ', ME.message]);
                cla(app.SpectrogramAxes);
                title(app.SpectrogramAxes, 'Error Generating Spectrogram');
                uialert(app.UIFigure, 'Spectrogram plotting failed.', 'Error');
            end
        end


        function resetDetectionResults(app)
            app.DetectionResultLabel.Text = '---';
            app.DetectionResultLabel.FontColor = [0 0 0]; % Reset to default
            app.ConfidenceGauge.Value = 0;
            app.ThreatIndicatorLamp.Color = [0.5 0.5 0.5]; % Grey
        end

        function audioPlaybackFinished(app)
            app.IsPlaying = false;
            if ~isempty(app.AudioData) % Ensure audio is still loaded
                 app.PlayAudioButton.Enable = 'on';
            end
            app.StopAudioButton.Enable = 'off';
            if isvalid(app.UIFigure) % Check if app is still running
                app.logMessage('Audio playback finished.');
            end
        end

        function ColormapDropDownValueChanged(app, ~)
            if ~isempty(app.AudioData) && ishghandle(app.SpectrogramAxes.Children) % Check if spectrogram is plotted
                colormap(app.SpectrogramAxes, app.ColormapDropDown.Value);
                app.logMessage(['Spectrogram colormap changed to: ', app.ColormapDropDown.Value]);
            end
        end

        function ClearLogButtonPushed(app, ~)
            app.LogTextArea.Value = {sprintf('%s Log Cleared.', datetime("now"))};
        end
    

        function ApplySettingsButtonPushed(app, ~)
            sensitivity = app.SensitivityKnob.Value; % Or slider
            dogBarkEnabled = app.DogBarkCheckBox.Value;
            stealthMode = app.StealthModeCheckBox.Value;
            selectedTheme = app.ThemeDropDown.Value;
            app.logMessage(sprintf('Simulated settings applied: Sensitivity=%s, DogBark=%d, Stealth=%d, Theme=%s', ...
                sensitivity, dogBarkEnabled, stealthMode, selectedTheme));
            uialert(app.UIFigure, 'Settings applied (simulation only). UI theme change requires restart or advanced coding.', 'Settings Applied');
            % Actual theme change would involve iterating through components and setting colors
            % if strcmp(selectedTheme, "Dark Pro")
            %   app.UIFigure.Color = [0.2 0.2 0.2];
            %   % ... and for all other components and text
            % elseif strcmp(selectedTheme, "Light Breeze")
            %   app.UIFigure.Color = [0.94 0.94 0.94]; % Default MATLAB light
            %   % ...
            % end
        end
        function updateUIEnableState(app)
            % --- START OF REPLACEMENT CODE ---

            % Get the text from the selected button in the Model Selection group
           
            % Enable/disable the threshold spinner based on the text
            
          % --- END OF REPLACEMENT CODE ---
        end


function applySettingsToUI(app, settings)
            % --- START OF REPLACEMENT CODE ---

            % Helper function to find and select the correct radio button
            function setSelectedButton(buttonGroup, buttonText)
                % Iterate through the children of the button group
                for i = 1:numel(buttonGroup.Children)
                    childComponent = buttonGroup.Children(i);
                    
                    % --- ADDED CHECK ---
                    % Only process components that are radio buttons.
                    % This will skip spinners, labels, etc.
                    if ~isa(childComponent, 'matlab.ui.control.RadioButton')
                        continue; % Skip to the next child
                    end
                    
                    childText = childComponent.Text; % Get the text property
                    
                    % Check if the text is a cell array (for multi-line text)
                    if iscell(childText)
                        childText = childText{1};
                    end
                    
                    % Compare the button's text with the target text
                    if strcmp(childText, buttonText)
                        % If it matches, set this as the selected object and exit
                        buttonGroup.SelectedObject = childComponent;
                        return;
                    end
                end
            end

            % Apply settings using the robust helper function
            setSelectedButton(app.ButtonGroup, settings.ModelSelection);
            

            if isfield(settings, 'AvailableModel') && strcmp(settings.ModelSelection, 'Select Manually')
                setSelectedButton(app.ButtonGroup_2, settings.AvailableModel);
            end
            if isfield(settings, 'ThresholdValue') && strcmp(settings.ThresholdSelection, 'Select Manually')
                app.ThresholdValueSpinner.Value = settings.ThresholdValue;
            end

            updateUIEnableState(app);
            % --- END OF REPLACEMENT CODE ---
        end


function settings = readSettingsFromUI(app)
    settings = struct();

    % Read model selection directly from the dropdown
    if isprop(app, 'ModelDropDown_2') && ~isempty(app.ModelDropDown_2)
        settings.AvailableModel = app.ModelDropDown_2.Value;
    else
        settings.AvailableModel = 'None';  % Default fallback
    end
end


function saveSettings(app)
    % Gathers settings from UI and saves them using setpref.
    currentSettings = readSettingsFromUI(app);
    fields = fieldnames(currentSettings);
    for i = 1:length(fields)
        fieldName = fields{i};
        % setpref saves preferences between MATLAB sessions
        setpref(app.PrefGroup, fieldName, currentSettings.(fieldName));
    end
    disp('Settings saved.');
    % Optional: Show a dialog to the user
    uialert(app.UIFigure, 'Your settings have been saved.', 'Settings Saved');
end

function loadSettings(app)
    % Loads settings using getpref. If a preference doesn't exist,
    % it uses the value from DefaultSettings.
    
    % Check if the preference group exists
    if ispref(app.PrefGroup)
        disp('Loading saved settings.');
        loadedSettings = struct();
        fields = fieldnames(app.DefaultSettings);
        for i = 1:length(fields)
            fieldName = fields{i};
            % getpref(group, name, defaultValue) is very convenient
            loadedSettings.(fieldName) = getpref(app.PrefGroup, fieldName, app.DefaultSettings.(fieldName));
        end
        applySettingsToUI(app, loadedSettings);
    else
        % If no preferences are saved, apply the hard-coded defaults
        disp('No saved settings found. Applying default settings.');
        applySettingsToUI(app, app.DefaultSettings);
    end
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % --- THIS IS THE NEW CODE SECTION ---
            % This function runs once when the app starts.
            
            % Set the initial state of the UI components.
            % By default, 'Autoselect' is chosen for both panels,
            % so we disable the manual selection areas.
            loadActiveModel(app); % Load the model based on the loaded settings
            % Disable the "Available Models" radio button group initially.


            app.DefaultSettings = struct( ...
    'ModelSelection', 'Autoselect best model', ...
    'ThresholdSelection', 'Autoselect (>80%)' ...
);


            loadSettings(app); % Load saved settings or apply defaults
            updateUIEnableState(app); % Update enabled/disabled states
            
            disp('App started. Manual selection panels are disabled.');
            app.SystemStatusLamp.Color = [0 1 0]; % Green - Ready
            app.TitleLabel.Text = "AuraShield - Sound Intelligence";
            app.TitleLabel.FontWeight = 'bold';
            app.TitleLabel.FontSize = 18;
            app.RecordDurationSpinner.Value = 5; % Default 5s
            app.AnalyzeSoundButton.Enable = 'off';
            app.PlayAudioButton.Enable = 'off';
            app.StopAudioButton.Enable = 'off';
            app.ConfidenceGauge.Value = 0;
            app.ThreatIndicatorLamp.Color = [0.5 0.5 0.5]; % Grey
            app.DetectionResultLabel.Text = '---';
            app.DetectionResultLabel.FontColor = [0 0 0]; % Black for light theme default
            app.ColormapDropDown.Items = {'parula', 'jet', 'hot', 'cool', 'gray', 'pink'};
            app.ColormapDropDown.Value = 'parula';


            % Start DateTime Timer (Optional)
            app.TimerForDateTime = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ... % Update every second
                'TimerFcn', @(~,~) app.updateDateTimeLabel());
            start(app.TimerForDateTime);
            % Initial plot styling
            title(app.WaveformAxes, 'Load or Record Audio');
            xlabel(app.WaveformAxes, 'Time (s)'); ylabel(app.WaveformAxes, 'Amplitude');
            grid(app.WaveformAxes, 'on');
            title(app.SpectrogramAxes, 'Audio Spectrogram');
            xlabel(app.SpectrogramAxes, 'Time (s)'); ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
            colorbar(app.SpectrogramAxes, 'off'); % Initially off
            app.logMessage('GUI startup complete. Waiting for audio input.');
        end
        function updateDateTimeLabel(app)
            if isvalid(app.UIFigure) % Check if app is still running
                 app.DateTimeLabel.Text = string(datetime("now"), 'dd-MMM-yyyy HH:mm:ss');
            end
        end
        function logMessage(app, message)
            currentDatetime = datetime("now");
            timestampStr = string(currentDatetime, 'HH:mm:ss');
            newLogEntry = sprintf('[%s] %s', timestampStr, message);
            fprintf('%s\n', newLogEntry);
            currentLog = app.LogTextArea.Value;
            if isempty(currentLog) || (isstring(currentLog) && currentLog == "") || (ischar(currentLog) && all(currentLog == ' '))
                app.LogTextArea.Value = newLogEntry;
            else
                if ischar(currentLog)
                    currentLog = {currentLog};
                end
                app.LogTextArea.Value = [currentLog; newLogEntry];
            end
            if numel(app.LogTextArea.Value) > 200
                app.LogTextArea.Value = app.LogTextArea.Value(end-199:end);
            end
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            
    % Open file dialog to select audio file
    [fileName, filePath] = uigetfile({'*.wav;*.mp3;*.m4a', 'Audio Files (*.wav, *.mp3, *.m4a)'}, 'Select Audio File');
    
    if fileName == 0  % User cancelled selection
        return;
    end
    
    % Initialize UI for loading
    app.CurrentAudioPath = fullfile(filePath, fileName);
    app.FileNameLabel.Text = fileName;
    app.logMessage(['Loading audio: ', app.CurrentAudioPath]);
    app.SystemStatusLamp.Color = [1 0.8 0]; % Yellow - Processing
    drawnow; % Force UI update
    
    try
        %% Audio Loading and Preprocessing
        [audioData, fs] = audioread(app.CurrentAudioPath);
        
        % Convert stereo to mono if needed
        if size(audioData, 2) > 1
            audioData = mean(audioData, 2);
        end
        
        % Store basic info
        duration = length(audioData)/fs;
        app.AudioInfoLabel.Text = sprintf('Duration: %.2fs | Fs: %d Hz | Channels: 1', duration, fs);
        
        % Normalize audio
        audioData = audioData/max(abs(audioData));
        
        % Simple noise reduction
        audioData = medfilt1(audioData, 3);
        
        %% Feature Extraction
        mfccFeatures = mfcc(audioData, fs, ...
                           'NumCoeffs', 13, ...
                           'LogEnergy', 'Replace');
        
        % Ensure correct MFCC shape (13×N)
        if size(mfccFeatures, 1) ~= 13
            mfccFeatures = mfccFeatures';
        end
        
        % Create feature vector (mean across time)
        inputFeature = mean(mfccFeatures, 2)';
        
        % Store in app properties
        app.AudioData = audioData;
        app.Fs = fs;
        app.MFCC = mfccFeatures;
       
        
        %% Update UI with Results


        % Visualization
        plotWaveform(app);
        plotSpectrogram(app);
        
        % Enable playback/analysis buttons
        app.AnalyzeSoundButton.Enable = 'on';
        app.PlayAudioButton.Enable = 'on';
        
        % Update status
        app.SystemStatusLamp.Color = [0 1 0]; % Green - Ready
        app.logMessage('Audio analysis completed successfully.');
        
    catch ME
        %% Error Handling
        app.SystemStatusLamp.Color = [1 0 0]; % Red - Error
        app.logMessage(['❌ Error: ', ME.message]);


        % Show error to user
        uialert(app.UIFigure, ME.message, 'Processing Error', ...
               'Icon', 'error');
    end
    try
    %% 🔹 SVM Evaluation
    modelData = load('models/svm_model.mat', 'svmModel');
    svmModel = modelData.svmModel;
    app.logMessage("✅ Loaded SVM model for evaluation.");

    data = load('src/feature_extraction/features_mfcc_spec.mat', 'testFeatures');
    testFeatures = data.testFeatures;

    mfccToTable = @(featureStruct) ...
        struct2table(arrayfun(@(x) struct( ...
            'Features', mean(x.mfcc, 2)', ...
            'Label', x.label), ...
            featureStruct));

    testTbl = mfccToTable(testFeatures);
    X_test = vertcat(testTbl.Features);
    Y_test = categorical(testTbl.Label);

    Y_pred_svm = predict(svmModel, X_test);
    accuracy_svm = sum(Y_pred_svm == Y_test) / numel(Y_test);
    accPercent_svm = accuracy_svm * 100;

    app.SVMAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_svm);
    app.SVMAccuracyGauge.Value = accPercent_svm;
    app.logMessage("📊 SVM evaluation complete and displayed.");

    %% 🔸 Random Forest Evaluation
    rfData = load('models/rfModel_MFCC.mat', 'rfModel');
    rfModel = rfData.rfModel;
    app.logMessage("✅ Loaded Random Forest model for evaluation.");

    Y_pred_rf = predict(rfModel, X_test);
    Y_pred_rf = categorical(Y_pred_rf);
    accuracy_rf = sum(Y_pred_rf == Y_test) / numel(Y_test);
    accPercent_rf = accuracy_rf * 100;

    app.RandomForestAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_rf);
    app.RandomForestAccuracyGauge.Value = accPercent_rf;
    app.logMessage("📊 Random Forest evaluation complete and displayed.");

    %% 🔵 KNN Evaluation
    knnData = load('models/knn_model.mat', 'knnModel');
    knnModel = knnData.knnModel;
    app.logMessage("✅ Loaded KNN model for evaluation.");

    Y_pred_knn = predict(knnModel, X_test);
    Y_pred_knn = categorical(Y_pred_knn);
    accuracy_knn = sum(Y_pred_knn == Y_test) / numel(Y_test);
    accPercent_knn = accuracy_knn * 100;

    app.KNNAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_knn);
    app.KNNAccuracyGauge.Value = accPercent_knn;
    app.logMessage("📊 KNN evaluation complete and displayed.");

catch ME
    app.logMessage(['❌ Evaluation error: ', ME.message]);

    % Reset UI elements on failure
    app.SVMAccuracyLabel.Text = "Accuracy: Error";
    app.SVMAccuracyGauge.Value = 0;

    app.RandomForestAccuracyLabel.Text = "Accuracy: Error";
    app.RandomForestAccuracyGauge.Value = 0;

    app.KNNAccuracyLabel.Text = "Accuracy: Error";
    app.KNNAccuracyGauge.Value = 0;
    end


    try
    % Load CNN model trained on MFCC
    % ⭐ CHANGE 'accuracy' to 'acc' here:
    cnnData = load('models/cnn_UrbanSound_MFCC.mat', 'net', 'acc'); 
    
    cnnModel = cnnData.net;
    % ⭐ CHANGE 'cnnData.accuracy' to 'cnnData.acc' here:
    cnnData = load('models/cnn_UrbanSound_MFCC.mat', 'net', 'acc'); 
    cnnModel = cnnData.net;
    cnnAccuracy = cnnData.acc * 100;


    % Update label and gauge in GUI
    app.CNNAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", cnnAccuracy);
    app.CNNAccuracyGauge.Value = cnnAccuracy;

    % Log message
    app.logMessage("📊 CNN (MFCC) evaluation complete and displayed.");

catch ME
    app.CNNAccuracyLabel.Text = "Accuracy: Error";
    app.CNNAccuracyGauge.Value = 0;
    app.logMessage(['❌ CNN evaluation failed: ', ME.message]);
    end


        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)


    % Step 1: Initialize recording duration
    duration = app.RecordDurationSpinner.Value;
    app.FileNameLabel.Text = sprintf('Recording for %ds...', duration);
    app.logMessage(sprintf('🎙️ Starting %.1fs recording...', duration));
    app.SystemStatusLamp.Color = [1 0.5 0]; % Orange - Recording

    % Step 2: Disable UI
    app.LoadAudioButton.Enable = 'off';
    app.RecordAudioButton.Enable = 'off';
    app.AnalyzeSoundButton.Enable = 'off';
    drawnow;

    try
        % Step 3: Create and start recorder
        fs = 44100;
        bits = 16;
        channels = 1;
        
        recorder = audiorecorder(fs, bits, channels);
        app.logMessage('🎧 Using default input device for recording.');
        
        recordblocking(recorder, duration);
        audioData = getaudiodata(recorder);

        % Step 4: Validate audio
        if isempty(audioData) || all(audioData == 0)
            app.SystemStatusLamp.Color = [1 0 0]; % Red
            app.FileNameLabel.Text = 'Recording Failed';
            uialert(app.UIFigure, 'Recording failed or produced silent data.', 'Recording Error');
            app.logMessage('❌ No sound captured. Is the mic working or allowed by OS?');
            return;
        end

        % Step 5: Preprocess
        if size(audioData, 2) > 1
            audioData = mean(audioData, 2);
        end
        audioData = audioData / max(abs(audioData));  % Normalize
        audioData = medfilt1(audioData, 3);           % Optional noise filter

        % Step 6: MFCC extraction
        mfccFeatures = mfcc(audioData, fs, 'NumCoeffs', 13, 'LogEnergy', 'Replace');
        if size(mfccFeatures, 1) ~= 13
            mfccFeatures = mfccFeatures';
        end

        % Step 7: Store
        app.AudioData = audioData;
        app.Fs = fs;
        app.MFCC = mfccFeatures;
        app.CurrentAudioPath = '';

        % Step 8: GUI update
        duration_rec = length(audioData) / fs;
        app.FileNameLabel.Text = sprintf('Recorded Audio (%.1fs)', duration);
        app.AudioInfoLabel.Text = sprintf('Duration: %.2fs\nFs: %d Hz\nChannels: 1', ...
                                          duration_rec, fs);

        plotWaveform(app);
        plotSpectrogram(app);

        app.AnalyzeSoundButton.Enable = 'on';
        app.PlayAudioButton.Enable = 'on';
        app.SystemStatusLamp.Color = [0 1 0];  % Green
        app.logMessage('✅ Recording successful and preprocessed.');
        app.logMessage('📌 MFCC features extracted and ready.');

        resetDetectionResults(app);

    catch ME
        app.SystemStatusLamp.Color = [1 0 0]; % Red
        app.FileNameLabel.Text = 'Recording Failed';
        app.logMessage(['❌ Error: ', ME.message]);
        uialert(app.UIFigure, ME.message, 'Recording Error');
    end

    % Step 9: Re-enable buttons
    app.LoadAudioButton.Enable = 'on';
    app.RecordAudioButton.Enable = 'on';


    try
    %% 🔹 SVM Evaluation
    modelData = load('models/svm_model.mat', 'svmModel');
    svmModel = modelData.svmModel;
    app.logMessage("✅ Loaded SVM model for evaluation.");

    data = load('src/feature_extraction/features_mfcc_spec.mat', 'testFeatures');
    testFeatures = data.testFeatures;

    mfccToTable = @(featureStruct) ...
        struct2table(arrayfun(@(x) struct( ...
            'Features', mean(x.mfcc, 2)', ...
            'Label', x.label), ...
            featureStruct));

    testTbl = mfccToTable(testFeatures);
    X_test = vertcat(testTbl.Features);
    Y_test = categorical(testTbl.Label);

    Y_pred_svm = predict(svmModel, X_test);
    accuracy_svm = sum(Y_pred_svm == Y_test) / numel(Y_test);
    accPercent_svm = accuracy_svm * 100;

    app.SVMAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_svm);
    app.SVMAccuracyGauge.Value = accPercent_svm;
    app.logMessage("📊 SVM evaluation complete and displayed.");

    %% 🔸 Random Forest Evaluation
    rfData = load('models/rfModel_MFCC.mat', 'rfModel');
    rfModel = rfData.rfModel;
    app.logMessage("✅ Loaded Random Forest model for evaluation.");

    Y_pred_rf = predict(rfModel, X_test);
    Y_pred_rf = categorical(Y_pred_rf);
    accuracy_rf = sum(Y_pred_rf == Y_test) / numel(Y_test);
    accPercent_rf = accuracy_rf * 100;

    app.RandomForestAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_rf);
    app.RandomForestAccuracyGauge.Value = accPercent_rf;
    app.logMessage("📊 Random Forest evaluation complete and displayed.");

    %% 🔵 KNN Evaluation
    knnData = load('models/knn_model.mat', 'knnModel');
    knnModel = knnData.knnModel;
    app.logMessage("✅ Loaded KNN model for evaluation.");

    Y_pred_knn = predict(knnModel, X_test);
    Y_pred_knn = categorical(Y_pred_knn);
    accuracy_knn = sum(Y_pred_knn == Y_test) / numel(Y_test);
    accPercent_knn = accuracy_knn * 100;

    app.KNNAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_knn);
    app.KNNAccuracyGauge.Value = accPercent_knn;
    app.logMessage("📊 KNN evaluation complete and displayed.");

catch ME
    app.logMessage(['❌ Evaluation error: ', ME.message]);

    % Reset UI elements on failure
    app.SVMAccuracyLabel.Text = "Accuracy: Error";
    app.SVMAccuracyGauge.Value = 0;

    app.RandomForestAccuracyLabel.Text = "Accuracy: Error";
    app.RandomForestAccuracyGauge.Value = 0;

    app.KNNAccuracyLabel.Text = "Accuracy: Error";
    app.KNNAccuracyGauge.Value = 0;
    end


    try
    % Load CNN model trained on MFCC
    % ⭐ CHANGE 'accuracy' to 'acc' here:
    cnnData = load('models/cnn_UrbanSound_MFCC.mat', 'net', 'acc'); 
    
    cnnModel = cnnData.net;
    % ⭐ CHANGE 'cnnData.accuracy' to 'cnnData.acc' here:
    cnnData = load('models/cnn_UrbanSound_MFCC.mat', 'net', 'acc'); 
    cnnModel = cnnData.net;
    cnnAccuracy = cnnData.acc * 100;


    % Update label and gauge in GUI
    app.CNNAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", cnnAccuracy);
    app.CNNAccuracyGauge.Value = cnnAccuracy;

    % Log message
    app.logMessage("📊 CNN (MFCC) evaluation complete and displayed.");

catch ME
    app.CNNAccuracyLabel.Text = "Accuracy: Error";
    app.CNNAccuracyGauge.Value = 0;
    app.logMessage(['❌ CNN evaluation failed: ', ME.message]);
    end

        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            if ~isempty(app.AudioData) && ~app.IsPlaying
                app.AudioPlayer = audioplayer(app.AudioData, app.Fs);
                app.AudioPlayer.StopFcn = @(~,~) audioPlaybackFinished(app);
                play(app.AudioPlayer);
                app.IsPlaying = true;
                app.PlayAudioButton.Enable = 'off';
                app.StopAudioButton.Enable = 'on';
                app.logMessage('Audio playback started.');
            end
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            if app.IsPlaying && ~isempty(app.AudioPlayer)
                stop(app.AudioPlayer);
                % StopFcn will handle resetting IsPlaying and button states
            end
        end

        % Button pushed function: AnalyzeSoundButton
        function AnalyzeSoundButtonPushed(app, event)
        
    try
        if isempty(app.MFCC)
            uialert(app.UIFigure, 'No audio loaded. Please load audio first.', 'Missing Input');
            return;
        end

        % Get model from saved settings
        selectedModel = app.SelectedModel;
        
        prediction_class = 'N/A';
        confidence_percent = 0;
        
        % Ensure app.MFCC is 13xT or T x 13, and convert to 13xT for consistency
        [rows, cols] = size(app.MFCC);
        mfccFeatures_for_prediction = app.MFCC; % Use a temp variable
        if rows ~= 13 && cols == 13
            mfccFeatures_for_prediction = mfccFeatures_for_prediction'; % Transpose if it's 13 columns
        elseif rows ~= 13
            % This indicates a problem with MFCC extraction. Alert or handle.
            uialert(app.UIFigure, sprintf("MFCC format unexpected: %dx%d. Need 13 MFCCs.", rows, cols), 'MFCC Error');
            app.SystemStatusLamp.Color = [1 0 0]; % Red - Error
            app.DetectionResultLabel.Text = "Prediction Error"; % Updated label
            
          
            return; % Stop processing if MFCC is wrong
        end

        switch selectedModel
            case 'SVM'
                modelData = load('models/svm_model.mat', 'svmModel');
                model = modelData.svmModel;
                
                % SVM/KNN/RF use mean MFCC (1x13 vector)
                inputFeature = mean(mfccFeatures_for_prediction, 2)'; % Average MFCCs over time for 1x13 vector
                
                % Predict and get scores for confidence
                [Y_pred, scores] = predict(model, inputFeature);
                prediction_class = string(Y_pred); % For categorical output from SVM
                
                % Get confidence (max score for the predicted class)
                [max_score, ~] = max(scores);
                confidence_percent = max_score * 100;

            case 'Random Forest'
                modelData = load('models/rfModel_MFCC.mat', 'rfModel');
                model = modelData.rfModel;
                
                inputFeature = mean(mfccFeatures_for_prediction, 2)'; % Average MFCCs over time for 1x13 vector
                
                % Predict and get scores for confidence
                [Y_pred, scores] = predict(model, inputFeature);
                prediction_class = string(Y_pred{1}); % For cell array output from Random Forest
                
                % Get confidence (max score for the predicted class)
                [max_score, ~] = max(scores);
                confidence_percent = max_score * 100;

            case 'KNN'
                modelData = load('models/knn_model.mat', 'knnModel');
                model = modelData.knnModel;
                
                inputFeature = mean(mfccFeatures_for_prediction, 2)'; % Average MFCCs over time for 1x13 vector
                
                % Predict and get scores for confidence
                % KNN predict doesn't directly give scores easily like others for classification.
                % You might need to use `knnsearch` and count nearest neighbors, or `fitcknn` with 'Prior' and then `predict`
                % For simplicity, let's assume predict just gives label. If you need real confidence, this case needs more work.
                Y_pred = predict(model, inputFeature);
                prediction_class = string(Y_pred);
                confidence_percent = 100; % Placeholder: KNN confidence is not directly a score like others.
                                          % If you want real confidence, you'd calculate proportion of nearest neighbors.

            case 'CNN'
                modelData = load('models/cnn_UrbanSound_MFCC.mat', 'net');
                model = modelData.net;

                % ⭐ CNN input requires specific reshaping and resizing ⭐
                % Assumes your CNN was trained on MFCCs resized to [64 64]
                resizeTo_cnn = [64 64]; 
                
                % Resize the current audio's MFCCs (which are 13xT) to 64x64
                cnn_input_mfcc = imresize(mfccFeatures_for_prediction, resizeTo_cnn);
                
                % Rescale to 0-1 range, as done during training
                cnn_input_mfcc = rescale(cnn_input_mfcc); 
                
                % Reshape to [Height x Width x Channels x BatchSize]
                % For a single grayscale image (MFCC), this is [64 x 64 x 1 x 1]
                inputFeature_cnn = reshape(cnn_input_mfcc, [resizeTo_cnn 1 1]);

                % Predict and get scores for confidence
                [Y_pred, scores] = classify(model, inputFeature_cnn); % classify can return scores
                prediction_class = string(Y_pred); % For categorical output from CNN
                
                % Get confidence (max score for the predicted class)
                [max_score, ~] = max(scores);
                confidence_percent = max_score * 100;

            otherwise
                uialert(app.UIFigure, 'No valid model selected in settings.', 'Prediction Error');
                app.DetectionResultLabel.Text = "Prediction: Error"; % Updated label
             
                app.logMessage('❌ Prediction error: No valid model selected.');
                return; % Exit function
        end
        
        % Display result in dedicated prediction labels
        app.DetectionResultLabel.Text = sprintf("Prediction: %s", prediction_class); % Updated label
       
        app.logMessage(sprintf("✅ Prediction complete using %s: %s (Confidence: %.2f%%)", selectedModel, prediction_class, confidence_percent));
    
    % 🔹 Update Detection Output
        app.DetectionResultLabel.Text = sprintf("Prediction: %s", prediction_class);
        app.ConfidenceGauge.Value = confidence_percent;
        
        app.logMessage(sprintf("✅ Prediction complete using %s: %s (Confidence: %.2f%%)", selectedModel, prediction_class, confidence_percent));

        % 🔸 Update ThreatIndicatorLamp color
        normalizedClass = lower(strrep(prediction_class, ' ', '_'));
        switch normalizedClass
            case {'gun_shot'}
                app.ThreatIndicatorLamp.Color = [1 0 0];   % 🔴 High threat
            case {'siren', 'jackhammer', 'drilling', 'car_horn'}
                app.ThreatIndicatorLamp.Color = [1 1 0];   % 🟡 Medium threat
            case {'air_conditioner', 'children_playing', 'dog_bark', 'engine_idling', 'street_music'}
                app.ThreatIndicatorLamp.Color = [0 1 0];   % 🟢 Low threat
            otherwise
                app.ThreatIndicatorLamp.Color = [0.5 0.5 0.5]; % ⚪ Unknown
        end

    catch ME
        % Update dedicated prediction UI elements on error
        app.DetectionResultLabel.Text = "Prediction: Error"; % Updated label
        
        app.logMessage(['❌ Prediction error: ', ME.message]);
        uialert(app.UIFigure, ['Prediction failed: ' ME.message], 'Prediction Error', 'Icon', 'error');
    end


% You might need the ternary helper function if you use it for CNN features:
% function out = ternary(cond, a, b)
%     if cond
%         out = a;
%     else
%         out = b;
%     end
% end

% You might need the ternary helper function if you use it for CNN features:
% function out = ternary(cond, a, b)
%     if cond
%         out = a;
%     else
%         out = b;
%     end
% end


        end

        % Button pushed function: SaveSnapshotButton
        function SaveSnapshotButtonPushed(app, event)


    if isempty(app.AudioData)
        app.logMessage('Nothing to save. Load or record audio first.');
        return;
    end

    app.logMessage('Initiating snapshot save...');

    % Open a dialog to choose save location and file name
    [fileName, filePath, ~] = uiputfile(...
        {'*.png', 'PNG Image (*.png)'; ...
         '*.jpg', 'JPEG Image (*.jpg)'; ...
         '*.pdf', 'PDF Document (*.pdf)'}, ...
        'Save App Snapshot', ...
        'AuraShield_AppSnapshot.png');

    if fileName ~= 0
        fullFilePath = fullfile(filePath, fileName);

        try
            % ✅ Save full app UI
            exportapp(app.UIFigure, fullFilePath);
            app.logMessage(['✅ Full App snapshot saved to: ', fullFilePath]);

        catch ME
            app.logMessage(['❌ Error saving snapshot: ', ME.message]);
            uialert(app.UIFigure, ['Could not save snapshot: ' ME.message], ...
                    'Save Error', 'Icon', 'error');
        end
    else
        app.logMessage('Snapshot save cancelled.');
    end


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.WaveformAxes); title(app.WaveformAxes, 'Load or Record Audio');
            cla(app.SpectrogramAxes); title(app.SpectrogramAxes, 'Audio Spectrogram'); colorbar(app.SpectrogramAxes, 'off');
            app.AudioData = []; app.Fs = [];
            app.FileNameLabel.Text = 'No file loaded';
            app.AudioInfoLabel.Text = 'Duration: --s, Fs: --Hz, Channels: --';
            resetDetectionResults(app);
            app.AnalyzeSoundButton.Enable = 'off';
            app.PlayAudioButton.Enable = 'off';
            app.StopAudioButton.Enable = 'off';
            if app.IsPlaying && ~isempty(app.AudioPlayer), stop(app.AudioPlayer); end
            app.logMessage('Analysis cleared.');
        end

        % Button down function: WaveformAxes
        function WaveformAxesButtonDown(app, event)
            if isempty(app.AudioData), return; end
            timeVector = (0:length(app.AudioData)-1) / app.Fs;
            plot(app.WaveformAxes, timeVector, app.AudioData);
            xlabel(app.WaveformAxes, 'Time (s)');
            ylabel(app.WaveformAxes, 'Amplitude');
            title(app.WaveformAxes, 'Audio Waveform');
            grid(app.WaveformAxes, 'on');
            axis(app.WaveformAxes, 'tight');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
      
   
        end

        % Callback function
        function ButtonGroup_3SelectionChanged(app, event)
            
        end

        % Button pushed function: ResettoDefaultsButton
        function ResettoDefaultsButtonPushed(app, event)
            % Callback for the 'Reset to Defaults' button.
            disp('Resetting settings to default.');
            applySettingsToUI(app, app.DefaultSettings);
            % Optional: Show a confirmation dialog
            uialert(app.UIFigure, 'All settings have been reset to their default values.', 'Settings Reset');
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            % Get selected model from dropdown
    selectedModel = app.ModelDropDown_2.Value;

    % Store in app property
    app.SelectedModel = selectedModel;

    % Optional: show confirmation
    uialert(app.UIFigure, ['Saved model: ' selectedModel], 'Settings Saved');


        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged2(app, event)
             % Only replot if audio exists
            if ~isempty(app.AudioData) && ~isempty(app.Fs)
                plotSpectrogram(app);
            end
        end

        % Callback function
        function LearnMoreAboutTheProjectButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AboutProject;
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed2(app, event)
        
    % Clear the log display
    app.LogTextArea.Value = {''};  % Clears the log area properly

        end

        % Button pushed function: ExportLogButton
        function ExportLogButtonPushed2(app, event)
% --- Callback function for the 'Export Log' button

    % This function will be executed when the 'Export Log' button is clicked.

    % 1. Get the current log content from your UI component.
    %    Using app.LogTextArea.Value as indicated by your logMessage function.
    if isprop(app, 'LogTextArea') && isvalid(app.LogTextArea)
        logContent = app.LogTextArea.Value;
        % Convert cell array of strings to a single string with newlines if necessary,
        % as uitextarea.Value can return a cell array when WordWrap is on.
        if iscell(logContent)
            logContent = strjoin(logContent, newline);
        end
    else
        % Handle cases where the text area might not be found or is invalid.
        uialert(app.UIFigure, 'Event Log text area (app.LogTextArea) not found or invalid.', 'Error', 'Icon', 'error');
        % For demonstration, let's use a placeholder if the text area isn't found.
        logContent = 'No event log content found. This is placeholder text.';
    end

    % Check if the log content is empty.
    if isempty(logContent) || (isstring(logContent) && logContent == "") || (ischar(logContent) && all(logContent == ' '))
        uialert(app.UIFigure, 'The event log is empty. Nothing to export.', 'Information', 'Icon', 'info');
        return; % Exit the function if there's no content
    end

    % 2. Open a "Save File" dialog to let the user choose the file name and location.
    %    The default extension is .txt.
    [fileName, pathName] = uiputfile('*.txt', 'Save Event Log as Text File', 'EventLog.txt');

    % Check if the user cancelled the save operation.
    if isequal(fileName, 0) || isequal(pathName, 0)
        uialert(app.UIFigure, 'Export operation cancelled.', 'Information', 'Icon', 'info');
        return; % User cancelled, so exit the function
    end

    % Construct the full file path.
    fullPath = fullfile(pathName, fileName);

    % 3. Write the log content directly to the text file.
    try
        fid = fopen(fullPath, 'wt'); % Open file for writing text
        if fid == -1
            error('Could not open file for writing.');
        end
        fprintf(fid, '%s', logContent); % Write the log content
        fclose(fid); % Close the file
        uialert(app.UIFigure, ['Event log successfully exported to:', newline, fullPath], 'Export Complete', 'Icon', 'success');
    catch ME
        uialert(app.UIFigure, ['Error exporting log: ', ME.message], 'Export Error', 'Icon', 'error');
        if fid ~= -1
            fclose(fid); % Ensure file is closed even on error
        end
    end
   

        end

        % Callback function
        function AccuracyTextAreaValueChanged(app, event)
% Button callback to show accuracy in the same TextArea used for input

        end

        % Value changed function: ModelDropDown_2
        function ModelDropDown_2ValueChanged(app, event)
            % ValueChangedFcn for ModelDropDown

    selectedModel = app.ModelDropDown_2.Value;
    app.SelectedModel = selectedModel;  % Store in app property
    app.logMessage("Selected model updated to: " + selectedModel);

            % Create AboutTab
            app.AboutTab = uitab(app.MainTabGroup);
            app.AboutTab.Title = 'About ';
            app.AboutTab.Scrollable = 'on';

            % Create GridLayout8
            app.GridLayout8 = uigridlayout(app.AboutTab);
            app.GridLayout8.ColumnWidth = {'1x', '1.37x', '1.37x'};
            app.GridLayout8.RowHeight = {'8.3x', 43, '5.72x'};
            app.GridLayout8.ColumnSpacing = 0;
            app.GridLayout8.Padding = [0 10 0 10];

            % Create TabGroup
            app.TabGroup = uitabgroup(app.GridLayout8);
            app.TabGroup.Layout.Row = [1 3];
            app.TabGroup.Layout.Column = [1 3];

            % Create Home
            app.Home = uitab(app.TabGroup);
            app.Home.Title = 'Home';
            app.Home.BackgroundColor = [0.2314 0.6667 0.1961];
            app.Home.ForegroundColor = [0 0 0];

            % Create AuraShieldLabel
            app.AuraShieldLabel = uilabel(app.Home);
            app.AuraShieldLabel.HorizontalAlignment = 'center';
            app.AuraShieldLabel.FontSize = 48;
            app.AuraShieldLabel.FontWeight = 'bold';
            app.AuraShieldLabel.FontColor = [1 1 1];
            app.AuraShieldLabel.Position = [358 -100 308 94];
            app.AuraShieldLabel.Text = 'AuraShield';

            % Create EditField
            app.EditField = uilabel(app.Home, 'text');
            app.EditField.Editable = 'off';
            app.EditField.FontSize = 18;
            app.EditField.FontWeight = 'bold';
            app.EditField.BackgroundColor = [0.2314 0.6667 0.1961];
            app.EditField.Position = [50 -184 1046 40];
            app.EditField.Value = '            Advanced acoustic monitoring solution developed for DRDO using cutting-edge MATLAB algorithms';

            % Create LearnMoreAboutTheProjectButton
            app.ClicktoLearnMoreAboutTheProjectButton = uibutton(app.Home, 'push');
            app.ClicktoLearnMoreAboutTheProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LearnMoreAboutTheProjectButtonPushed, true);
            app.ClicktoLearnMoreAboutTheProjectButton.FontSize = 14;
            app.ClicktoLearnMoreAboutTheProjectButton.FontWeight = 'bold';
            app.ClicktoLearnMoreAboutTheProjectButton.Position = [387 -236 251 44];
            app.ClicktoLearnMoreAboutTheProjectButton.Text = 'Learn More About The Project';

            % Create CoreCapabilitiesLabel_3
            app.CoreCapabilitiesLabel_3 = uilabel(app.Home);
            app.CoreCapabilitiesLabel_3.BackgroundColor = [0.902 0.902 0.902];
            app.CoreCapabilitiesLabel_3.HorizontalAlignment = 'center';
            app.CoreCapabilitiesLabel_3.FontSize = 36;
            app.CoreCapabilitiesLabel_3.FontWeight = 'bold';
            app.CoreCapabilitiesLabel_3.Position = [338 -326 398 43];
            app.CoreCapabilitiesLabel_3.Text = 'Core Capabilities';

            % Create Panel_9
            app.Panel_9 = uipanel(app.Home);
            app.Panel_9.Position = [10 -496 263 163];

            % Create RealtimeProcessingLabel_3
            app.RealtimeProcessingLabel_3 = uilabel(app.Panel_9);
            app.RealtimeProcessingLabel_3.FontSize = 24;
            app.RealtimeProcessingLabel_3.FontWeight = 'bold';
            app.RealtimeProcessingLabel_3.Position = [6 112 251 31];
            app.RealtimeProcessingLabel_3.Text = 'Real-time Processing';

            % Create AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel_3
            app.AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel_3 = uilabel(app.Panel_9);
            app.AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel_3.WordWrap = 'on';
            app.AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel_3.FontSize = 18;
            app.AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel_3.Position = [5 33 258 75];
            app.AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel_3.Text = 'Advanced MATLAB algorithms for continuous acoustic monitoring and analysis';

            % Create Panel_8
            app.Panel_8 = uipanel(app.Home);
            app.Panel_8.Position = [281 -497 280 164];

            % Create ThreatDetectionLabel_3
            app.ThreatDetectionLabel_3 = uilabel(app.Panel_8);
            app.ThreatDetectionLabel_3.FontSize = 24;
            app.ThreatDetectionLabel_3.FontWeight = 'bold';
            app.ThreatDetectionLabel_3.Position = [9 113 195 31];
            app.ThreatDetectionLabel_3.Text = 'Threat Detection';

            % Create IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel_3
            app.IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel_3 = uilabel(app.Panel_8);
            app.IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel_3.WordWrap = 'on';
            app.IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel_3.FontSize = 18;
            app.IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel_3.Position = [9 42 278 66];
            app.IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel_3.Text = 'Intelligent pattern recognition for identifying security threats and intrusions';

            % Create Panel_7
            app.Panel_7 = uipanel(app.Home);
            app.Panel_7.Position = [567 -496 300 163];

            % Create AnalyticsReportingLabel_3
            app.AnalyticsReportingLabel_3 = uilabel(app.Panel_7);
            app.AnalyticsReportingLabel_3.FontSize = 24;
            app.AnalyticsReportingLabel_3.FontWeight = 'bold';
            app.AnalyticsReportingLabel_3.Position = [8 112 259 31];
            app.AnalyticsReportingLabel_3.Text = 'Analytics & Reporting';

            % Create ComprehensivedataanalysisandvisulizationforsecurityassessmentLabel_3
            app.ComprehensivedataanalysisandvisulizationforsecurityassessmentLabel_3 = uilabel(app.Panel_7);
            app.ComprehensivedataanalysisandvisulizationforsecurityassessmentLabel_3.WordWrap = 'on';
            app.ComprehensivedataanalysisandvisulizationforsecurityassessmentLabel_3.FontSize = 18;
            app.ComprehensivedataanalysisandvisulizationforsecurityassessmentLabel_3.Position = [8 33 280 80];
            app.ComprehensivedataanalysisandvisulizationforsecurityassessmentLabel_3.Text = 'Comprehensive data analysis and visulization for security assessment';

            % Create Panel_10
            app.Panel_10 = uipanel(app.Home);
            app.Panel_10.Position = [875 -496 220 163];

            % Create ConfigurableParametersLabel
            app.ConfigurableParametersLabel = uilabel(app.Panel_10);
            app.ConfigurableParametersLabel.WordWrap = 'on';
            app.ConfigurableParametersLabel.FontSize = 24;
            app.ConfigurableParametersLabel.FontWeight = 'bold';
            app.ConfigurableParametersLabel.Position = [8 97 198 59];
            app.ConfigurableParametersLabel.Text = 'Configurable Parameters';

            % Create UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel
            app.UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel = uilabel(app.Panel_10);
            app.UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel.WordWrap = 'on';
            app.UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel.FontSize = 18;
            app.UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel.Position = [8 23 198 66];
            app.UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel.Text = 'User defined thresholds and filtering options for optimal performance';

            % Create SoundIntrusionSystemLabel
            app.SoundIntrusionSystemLabel = uilabel(app.Home);
            app.SoundIntrusionSystemLabel.HorizontalAlignment = 'center';
            app.SoundIntrusionSystemLabel.FontSize = 20;
            app.SoundIntrusionSystemLabel.FontWeight = 'bold';
            app.SoundIntrusionSystemLabel.Position = [319 -135 384 33];
            app.SoundIntrusionSystemLabel.Text = 'Sound Intrusion System';

            % Create AboutProject
            app.AboutProject = uitab(app.TabGroup);
            app.AboutProject.Title = 'AboutProject';

            % Create PROJECTHIGHLIGHTSPanel
            app.PROJECTHIGHLIGHTSPanel = uipanel(app.AboutProject);
            app.PROJECTHIGHLIGHTSPanel.TitlePosition = 'centertop';
            app.PROJECTHIGHLIGHTSPanel.Title = '                                             PROJECT HIGHLIGHTS                                                      ';
            app.PROJECTHIGHLIGHTSPanel.BackgroundColor = [0.4235 0.9569 1];
            app.PROJECTHIGHLIGHTSPanel.HandleVisibility = 'off';
            app.PROJECTHIGHLIGHTSPanel.FontWeight = 'bold';
            app.PROJECTHIGHLIGHTSPanel.FontSize = 18;
            app.PROJECTHIGHLIGHTSPanel.Position = [21 -205 1066 201];

            % Create GridLayout9
            app.GridLayout9 = uigridlayout(app.PROJECTHIGHLIGHTSPanel);
            app.GridLayout9.RowHeight = {'1x', '1x', '1x'};

            % Create MATLABbasedimplementationforrobustsignalprocessingLabel
            app.MATLABbasedimplementationforrobustsignalprocessingLabel = uilabel(app.GridLayout9);
            app.MATLABbasedimplementationforrobustsignalprocessingLabel.BackgroundColor = [0.949 0.9216 0.6078];
            app.MATLABbasedimplementationforrobustsignalprocessingLabel.FontSize = 16;
            app.MATLABbasedimplementationforrobustsignalprocessingLabel.Layout.Row = 1;
            app.MATLABbasedimplementationforrobustsignalprocessingLabel.Layout.Column = 1;
            app.MATLABbasedimplementationforrobustsignalprocessingLabel.Text = '● MATLAB-based implementation for robust signal processing';

            % Create RealtimevisualizationofacousticdataandthreatlevelsLabel
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel = uilabel(app.GridLayout9);
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel.BackgroundColor = [0.9608 0.9333 0.6235];
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel.FontSize = 16;
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel.Layout.Row = 1;
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel.Layout.Column = 2;
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel.Text = '● Real-time visualization of acoustic data and threat levels';

            % Create ComprehensiveloggingandaudittrailfunctionalityLabel
            app.ComprehensiveloggingandaudittrailfunctionalityLabel = uilabel(app.GridLayout9);
            app.ComprehensiveloggingandaudittrailfunctionalityLabel.BackgroundColor = [0.949 0.9216 0.6078];
            app.ComprehensiveloggingandaudittrailfunctionalityLabel.FontSize = 16;
            app.ComprehensiveloggingandaudittrailfunctionalityLabel.Layout.Row = 2;
            app.ComprehensiveloggingandaudittrailfunctionalityLabel.Layout.Column = 1;
            app.ComprehensiveloggingandaudittrailfunctionalityLabel.Text = '● Comprehensive logging and audit trail functionality';

            % Create MachinelearningalgorithmsforimproveddetectionaccuracyLabel
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel = uilabel(app.GridLayout9);
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel.BackgroundColor = [0.9686 0.9412 0.6314];
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel.FontSize = 16;
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel.Layout.Row = 2;
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel.Layout.Column = 2;
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel.Text = '● Machine learning algorithms for improved detection accuracy';

            % Create IntegrationcapabilitieswithexistingsecurityinfrastructureLabel
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel = uilabel(app.GridLayout9);
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel.BackgroundColor = [0.9608 0.9294 0.6157];
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel.FontSize = 16;
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel.Layout.Row = 3;
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel.Layout.Column = 2;
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel.Text = '● Integration capabilities with existing security infrastructure';

            % Create ScalablearchitecturesupportingmultiplesensornetworksLabel
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel = uilabel(app.GridLayout9);
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel.BackgroundColor = [0.9608 0.9294 0.6157];
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel.FontSize = 16;
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel.Layout.Row = 3;
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel.Layout.Column = 1;
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel.Text = ' ● Scalable architecture supporting multiple sensor networks';

            % Create DEVELOPMENTTEAMPanel
            app.DEVELOPMENTTEAMPanel = uipanel(app.AboutProject);
            app.DEVELOPMENTTEAMPanel.TitlePosition = 'centertop';
            app.DEVELOPMENTTEAMPanel.Title = 'DEVELOPMENT TEAM';
            app.DEVELOPMENTTEAMPanel.BackgroundColor = [0.4235 0.9569 1];
            app.DEVELOPMENTTEAMPanel.FontWeight = 'bold';
            app.DEVELOPMENTTEAMPanel.FontSize = 18;
            app.DEVELOPMENTTEAMPanel.Position = [21 -484 1065 260];

            % Create GridLayout10
            app.GridLayout10 = uigridlayout(app.DEVELOPMENTTEAMPanel);
            app.GridLayout10.ColumnWidth = {'1x', '1x', '1x'};

            % Create AnshitaKapkotiLabel
            app.AnshitaKapkotiLabel = uilabel(app.GridLayout10);
            app.AnshitaKapkotiLabel.BackgroundColor = [0.6275 0.9804 0.8039];
            app.AnshitaKapkotiLabel.HorizontalAlignment = 'center';
            app.AnshitaKapkotiLabel.FontSize = 18;
            app.AnshitaKapkotiLabel.FontWeight = 'bold';
            app.AnshitaKapkotiLabel.Layout.Row = 1;
            app.AnshitaKapkotiLabel.Layout.Column = 3;
            app.AnshitaKapkotiLabel.Text = 'Anshita Kapkoti';

            % Create AyushiMishraLabel
            app.AyushiMishraLabel = uilabel(app.GridLayout10);
            app.AyushiMishraLabel.BackgroundColor = [0.6078 0.949 0.7804];
            app.AyushiMishraLabel.HorizontalAlignment = 'center';
            app.AyushiMishraLabel.FontSize = 18;
            app.AyushiMishraLabel.FontWeight = 'bold';
            app.AyushiMishraLabel.Layout.Row = 1;
            app.AyushiMishraLabel.Layout.Column = 2;
            app.AyushiMishraLabel.Text = 'Ayushi Mishra';

            % Create TejasPalLabel
            app.TejasPalLabel = uilabel(app.GridLayout10);
            app.TejasPalLabel.BackgroundColor = [0.5686 0.9294 0.749];
            app.TejasPalLabel.HorizontalAlignment = 'center';
            app.TejasPalLabel.FontSize = 18;
            app.TejasPalLabel.FontWeight = 'bold';
            app.TejasPalLabel.Layout.Row = 2;
            app.TejasPalLabel.Layout.Column = 1;
            app.TejasPalLabel.Text = 'Tejas Pal';

            % Create AdityaRajSrivastavaLabel
            app.AdityaRajSrivastavaLabel = uilabel(app.GridLayout10);
            app.AdityaRajSrivastavaLabel.BackgroundColor = [0.6196 0.9686 0.7961];
            app.AdityaRajSrivastavaLabel.HorizontalAlignment = 'center';
            app.AdityaRajSrivastavaLabel.FontSize = 18;
            app.AdityaRajSrivastavaLabel.FontWeight = 'bold';
            app.AdityaRajSrivastavaLabel.Layout.Row = 2;
            app.AdityaRajSrivastavaLabel.Layout.Column = 2;
            app.AdityaRajSrivastavaLabel.Text = 'Aditya Raj Srivastava';

            % Create AmitanshSrivastavaLabel
            app.AmitanshSrivastavaLabel = uilabel(app.GridLayout10);
            app.AmitanshSrivastavaLabel.BackgroundColor = [0.5843 0.9412 0.7608];
            app.AmitanshSrivastavaLabel.HorizontalAlignment = 'center';
            app.AmitanshSrivastavaLabel.FontSize = 18;
            app.AmitanshSrivastavaLabel.FontWeight = 'bold';
            app.AmitanshSrivastavaLabel.Layout.Row = 1;
            app.AmitanshSrivastavaLabel.Layout.Column = 1;
            app.AmitanshSrivastavaLabel.Text = 'Amitansh Srivastava';

            % Create PriyanshuSrivastavaLabel
            app.PriyanshuSrivastavaLabel = uilabel(app.GridLayout10);
            app.PriyanshuSrivastavaLabel.BackgroundColor = [0.6 0.949 0.7725];
            app.PriyanshuSrivastavaLabel.HorizontalAlignment = 'center';
            app.PriyanshuSrivastavaLabel.FontSize = 18;
            app.PriyanshuSrivastavaLabel.FontWeight = 'bold';
            app.PriyanshuSrivastavaLabel.Layout.Row = 2;
            app.PriyanshuSrivastavaLabel.Layout.Column = 3;
            app.PriyanshuSrivastavaLabel.Text = 'Priyanshu Srivastava';

            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        
            
        end

        % Button pushed function: ClicktoLearnMoreAboutTheProjectButton
        function ClicktoLearnMoreAboutTheProjectButtonPushed2(app, event)
            app.TabGroup.SelectedTab = app.AboutProject;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.HeaderPanel = uipanel(app.MainGridLayout);
            app.GridLayout2 = uigridlayout(app.HeaderPanel);
            app.LogoImage = uiimage(app.GridLayout2);
            app.LogoImage.ImageSource = fullfile(pathToMLAPP, 'logo.jpg');
            app.LampLabel = uilabel(app.GridLayout2);
            app.LampLabel.Text = 'Lamp';
            app.SystemStatusLamp = uilamp(app.GridLayout2);
            app.TitleLabel = uilabel(app.GridLayout2);
            app.TitleLabel.Text = 'AuraShield- Sound Intrusion System';
            app.DateTimeLabel = uilabel(app.GridLayout2);
            app.DateTimeLabel.Text = 'dd-mmm-yyyy HH:MM:SS';
            app.MainTabGroup = uitabgroup(app.MainGridLayout);
            app.DetectorTab = uitab(app.MainTabGroup);
            app.DetectorTab.Title = 'Detector';
            app.GridLayout3 = uigridlayout(app.DetectorTab);
            app.AudioControlPanel = uipanel(app.GridLayout3);
            app.AudioControlPanel.Title = 'Audio Control';
            app.GridLayout4 = uigridlayout(app.AudioControlPanel);
            app.WaveformAxes = uiaxes(app.GridLayout4);
            title(app.WaveformAxes, 'Title')
            xlabel(app.WaveformAxes, 'X')
            ylabel(app.WaveformAxes, 'Y')
            zlabel(app.WaveformAxes, 'Z')
            app.WaveformAxes.ButtonDownFcn = createCallbackFcn(app, @WaveformAxesButtonDown, true);
            app.LoadAudioButton = uibutton(app.GridLayout4, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio File';
            app.FileNameLabel = uilabel(app.GridLayout4);
            app.FileNameLabel.Text = 'No file loaded';
            app.ORLabel = uilabel(app.GridLayout4);
            app.ORLabel.Text = 'OR';
            app.RecordAudioButton = uibutton(app.GridLayout4, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.RecordDurationSpinner = uispinner(app.GridLayout4);
            app.RecordDurationSpinner.Limits = [1 60];
            app.RecordDurationSpinner.Placeholder = 'Duration (s)';
            app.RecordDurationSpinner.Value = 5;
            app.AudioInfoLabel = uilabel(app.GridLayout4);
            app.AudioInfoLabel.Text = 'Duration: --s, Fs: --Hz, Channels: --';
            app.PlayAudioButton = uibutton(app.GridLayout4, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play';
            app.StopAudioButton = uibutton(app.GridLayout4, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop';
            app.AnalysisPanel = uipanel(app.GridLayout3);
            app.AnalysisPanel.Title = 'Sound Analysis';
            app.GridLayout5 = uigridlayout(app.AnalysisPanel);
            app.SpectrogramAxes = uiaxes(app.GridLayout5);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'X')
            ylabel(app.SpectrogramAxes, 'Y')
            zlabel(app.SpectrogramAxes, 'Z')
            app.ColormapDropDownLabel = uilabel(app.GridLayout5);
            app.ColormapDropDownLabel.Text = 'Colormap:';
            app.ColormapDropDown = uidropdown(app.GridLayout5);
            app.ColormapDropDown.Items = {'parula', 'jet', 'hot', 'cool', 'gray', 'pink'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged2, true);
            app.ColormapDropDown.Value = 'parula';
            app.AnalyzeSoundButton = uibutton(app.GridLayout5, 'push');
            app.AnalyzeSoundButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSoundButtonPushed, true);
            app.AnalyzeSoundButton.Text = 'Analyse Sound';
            app.DetectionTitleLabel = uilabel(app.GridLayout5);
            app.DetectionTitleLabel.Text = 'Threat Detection:';
            app.DetectionResultLabel = uilabel(app.GridLayout5);
            app.DetectionResultLabel.Text = '---------';
            app.ConfidenceTitleLabel = uilabel(app.GridLayout5);
            app.ConfidenceTitleLabel.Text = 'Confidence Level:';
            app.ConfidenceGauge = uigauge(app.GridLayout5, 'circular');
            app.ThreatLevelLabel = uilabel(app.GridLayout5);
            app.ThreatLevelLabel.Text = 'Threat Level:';
            app.ThreatIndicatorLamp = uilamp(app.GridLayout5);
            app.SaveSnapshotButton = uibutton(app.GridLayout5, 'push');
            app.SaveSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSnapshotButtonPushed, true);
            app.SaveSnapshotButton.Text = 'Save Snapshot';
            app.ClearButton = uibutton(app.GridLayout5, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear All';
            app.EvaluationTab = uitab(app.MainTabGroup);
            app.EvaluationTab.Title = 'Evaluation';
            app.Panel_17 = uipanel(app.EvaluationTab);
            app.SVMLabel = uilabel(app.Panel_17);
            app.SVMLabel.Text = 'SVM';
            app.SVMAccuracyLabel = uilabel(app.Panel_17);
            app.SVMAccuracyLabel.Text = 'SVMAccuracyLabel';
            app.SVMAccuracyGauge = uigauge(app.Panel_17, 'circular');
            app.Panel_12 = uipanel(app.EvaluationTab);
            app.RandomForestLabel = uilabel(app.Panel_12);
            app.RandomForestLabel.Text = 'Random Forest';
            app.RandomForestAccuracyGauge = uigauge(app.Panel_12, 'circular');
            app.RandomForestAccuracyLabel = uilabel(app.Panel_12);
            app.RandomForestAccuracyLabel.Text = 'RandomForest AccuracyLabel';
            app.Panel_13 = uipanel(app.EvaluationTab);
            app.CNNLabel = uilabel(app.Panel_13);
            app.CNNLabel.Text = '         CNN';
            app.CNNAccuracyGauge = uigauge(app.Panel_13, 'circular');
            app.CNNAccuracyLabel = uilabel(app.Panel_13);
            app.CNNAccuracyLabel.Text = 'CNN AccuracyLabel';
            app.Panel_14 = uipanel(app.EvaluationTab);
            app.KNNLabel = uilabel(app.Panel_14);
            app.KNNLabel.Text = '         KNN';
            app.KNNAccuracyGauge = uigauge(app.Panel_14, 'circular');
            app.KNNAccuracyLabel = uilabel(app.Panel_14);
            app.KNNAccuracyLabel.Text = 'KNNAccuracyLabel';
            app.Panel_15 = uipanel(app.EvaluationTab);
            app.LSTMLabel = uilabel(app.Panel_15);
            app.LSTMLabel.Text = '         LSTM';
            app.LSTMGaugeLabel = uilabel(app.Panel_15);
            app.LSTMGaugeLabel.Text = 'LSTMGauge';
            app.LSTMGauge = uigauge(app.Panel_15, 'circular');
            app.LSTMAccuracyLabel = uilabel(app.Panel_15);
            app.LSTMAccuracyLabel.Text = 'LSTM AccuracyLabel';
            app.EventLogTab = uitab(app.MainTabGroup);
            app.EventLogTab.Title = 'Event Log';
            app.GridLayout6 = uigridlayout(app.EventLogTab);
            app.EventLogsLabel = uilabel(app.GridLayout6);
            app.EventLogsLabel.Text = 'Event Logs';
            app.LogTextArea = uitextarea(app.GridLayout6);
            app.ClearLogButton = uibutton(app.GridLayout6, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed2, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.ExportLogButton = uibutton(app.GridLayout6, 'push');
            app.ExportLogButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLogButtonPushed2, true);
            app.ExportLogButton.Text = 'Export Log';
            app.SettingsTab = uitab(app.MainTabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout7 = uigridlayout(app.SettingsTab);
            app.ModelSelectionPanel = uipanel(app.GridLayout7);
            app.ModelSelectionPanel.Title = 'Model Selection';
            app.ButtonGroup = uibuttongroup(app.ModelSelectionPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SaveSettingsButton = uibutton(app.ButtonGroup, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.ResettoDefaultsButton = uibutton(app.ButtonGroup, 'push');
            app.ResettoDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultsButtonPushed, true);
            app.ResettoDefaultsButton.Text = 'Reset to default';
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = 'ModelDropDown';
            app.ModelDropDown_2 = uidropdown(app.ButtonGroup);
            app.ModelDropDown_2.Items = {'SVM', 'Random Forest', 'KNN', 'CNN', 'LSTM'};
            app.ModelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModelDropDown_2ValueChanged, true);
            app.ModelDropDown_2.Value = 'SVM';
            app.SelectModelfromtheDropDownLabel = uilabel(app.ButtonGroup);
            app.SelectModelfromtheDropDownLabel.Text = 'Select Model from the DropDown';
            app.AboutTab = uitab(app.MainTabGroup);
            app.AboutTab.Title = 'About ';
            app.GridLayout8 = uigridlayout(app.AboutTab);
            app.TabGroup = uitabgroup(app.GridLayout8);
            app.Home = uitab(app.TabGroup);
            app.Home.Title = 'Home';
            app.GridLayout13 = uigridlayout(app.Home);
            app.AdvancedacousticmonitoringsolutiondevelopedforDRDOusingcuttingedgeMATLABalgorithmsLabel = uilabel(app.GridLayout13);
            app.AdvancedacousticmonitoringsolutiondevelopedforDRDOusingcuttingedgeMATLABalgorithmsLabel.Text = 'Advanced acoustic monitoring solution developed for DRDO using cutting-edge MATLAB algorithms';
            app.SoundIntrusionSystemLabel = uilabel(app.GridLayout13);
            app.SoundIntrusionSystemLabel.Text = 'Sound Intrusion System';
            app.AuraShieldLabel = uilabel(app.GridLayout13);
            app.AuraShieldLabel.Text = 'AuraShield';
            app.CoreCapabilitiesLabel_3 = uilabel(app.GridLayout13);
            app.CoreCapabilitiesLabel_3.Text = 'Core Capabilities';
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.Panel_18 = uipanel(app.GridLayout14);
            app.GridLayout15 = uigridlayout(app.Panel_18);
            app.ComprehensivedataanalysisandvisualizationforsecurityassessmentLabel = uilabel(app.GridLayout15);
            app.ComprehensivedataanalysisandvisualizationforsecurityassessmentLabel.Text = 'Comprehensive data analysis and visualization for security assessment';
            app.AnalyticsReportingLabel = uilabel(app.GridLayout15);
            app.AnalyticsReportingLabel.Text = 'Analytics & Reporting';
            app.Panel_19 = uipanel(app.GridLayout14);
            app.GridLayout16 = uigridlayout(app.Panel_19);
            app.IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel = uilabel(app.GridLayout16);
            app.IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel.Text = 'Intelligent pattern recognition for identifying security threats and intrusions';
            app.ThreatDetectionLabel = uilabel(app.GridLayout16);
            app.ThreatDetectionLabel.Text = 'Threat Detection';
            app.Panel_20 = uipanel(app.GridLayout14);
            app.GridLayout17 = uigridlayout(app.Panel_20);
            app.RealtimeProcessingLabel = uilabel(app.GridLayout17);
            app.RealtimeProcessingLabel.Text = 'Real-time Processing';
            app.AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel = uilabel(app.GridLayout17);
            app.AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel.Text = 'Advanced MATLAB algorithms for continuous acoustic monitoring and analysis';
            app.Panel_21 = uipanel(app.GridLayout14);
            app.GridLayout18 = uigridlayout(app.Panel_21);
            app.UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel = uilabel(app.GridLayout18);
            app.UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel.Text = 'User defined thresholds and filtering options for optimal performance';
            app.ConfigurableParametersLabel = uilabel(app.GridLayout18);
            app.ConfigurableParametersLabel.Text = 'Configurable Parameters';
            app.ClicktoLearnMoreAboutTheProjectButton = uibutton(app.GridLayout13, 'push');
            app.ClicktoLearnMoreAboutTheProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoLearnMoreAboutTheProjectButtonPushed2, true);
            app.ClicktoLearnMoreAboutTheProjectButton.Tooltip = {'Click to explore more'};
            app.ClicktoLearnMoreAboutTheProjectButton.Text = 'Click to  Learn More About The Project';
            app.AboutProject = uitab(app.TabGroup);
            app.AboutProject.Title = 'AboutProject';
            app.GridLayout12 = uigridlayout(app.AboutProject);
            app.PROJECTHIGHLIGHTSPanel = uipanel(app.GridLayout12);
            app.PROJECTHIGHLIGHTSPanel.Title = '                                             PROJECT HIGHLIGHTS                                                      ';
            app.GridLayout9 = uigridlayout(app.PROJECTHIGHLIGHTSPanel);
            app.MATLABbasedimplementationforrobustsignalprocessingLabel = uilabel(app.GridLayout9);
            app.MATLABbasedimplementationforrobustsignalprocessingLabel.Text = '● MATLAB-based implementation for robust signal processing';
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel = uilabel(app.GridLayout9);
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel.Text = '● Real-time visualization of acoustic data and threat levels';
            app.ComprehensiveloggingandaudittrailfunctionalityLabel = uilabel(app.GridLayout9);
            app.ComprehensiveloggingandaudittrailfunctionalityLabel.Text = '● Comprehensive logging and audit trail functionality';
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel = uilabel(app.GridLayout9);
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel.Text = '● Machine learning algorithms for improved detection accuracy';
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel = uilabel(app.GridLayout9);
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel.Text = '● Integration capabilities with existing security infrastructure';
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel = uilabel(app.GridLayout9);
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel.Text = ' ● Scalable architecture supporting multiple sensor networks';
            app.DEVELOPMENTTEAMPanel = uipanel(app.GridLayout12);
            app.DEVELOPMENTTEAMPanel.Title = 'DEVELOPMENT TEAM';
            app.GridLayout10 = uigridlayout(app.DEVELOPMENTTEAMPanel);
            app.AnshitaKapkotiLabel = uilabel(app.GridLayout10);
            app.AnshitaKapkotiLabel.Text = 'Anshita Kapkoti';
            app.AyushiMishraLabel = uilabel(app.GridLayout10);
            app.AyushiMishraLabel.Text = 'Ayushi Mishra';
            app.TejasPalLabel = uilabel(app.GridLayout10);
            app.TejasPalLabel.Text = 'Tejas Pal';
            app.AdityaRajSrivastavaLabel = uilabel(app.GridLayout10);
            app.AdityaRajSrivastavaLabel.Text = 'Aditya Raj Srivastava';
            app.AmitanshSrivastavaLabel = uilabel(app.GridLayout10);
            app.AmitanshSrivastavaLabel.Text = 'Amitansh Srivastava';
            app.PriyanshuSrivastavaLabel = uilabel(app.GridLayout10);
            app.PriyanshuSrivastavaLabel.Text = 'Priyanshu Srivastava';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drdoproject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end